classdef Lucy_dlg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LucyUIFigure        matlab.ui.Figure
        txtMessage          matlab.ui.control.TextArea
        btnPause            matlab.ui.control.Button
        btnStop             matlab.ui.control.Button
        btnRun              matlab.ui.control.Button
        edtNumBins          matlab.ui.control.NumericEditField
        binsEditFieldLabel  matlab.ui.control.Label
        edtTolerance        matlab.ui.control.NumericEditField
        ToleranceLabel      matlab.ui.control.Label
        ax3                 matlab.ui.control.UIAxes
        ax2                 matlab.ui.control.UIAxes
        ax1                 matlab.ui.control.UIAxes
    end


    properties (Access = private)
        dt % lag time of the van_Hove function to fit
        callingApp
        msd
        van_Hove
        IsStop=false;
        IsPaused=false;
        p_D;
        P_D;
        van_Hove_rec;
        dtP;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, msd, van_hove, dt)
            app.callingApp=caller;
            app.dt=dt;
            app.msd=msd;
            app.van_Hove=van_hove;

            % Initiate axes
            [~,ind]=min(abs(app.msd(:,1)-dt));
            cla(app.ax1,'reset');
            app.ax1.Box='on';
            legend(app.ax1,'off');
            N=app.van_Hove{ind,1};
            edges=app.van_Hove{ind,2};
            bins=edges(1:end-1)+diff(edges)./2;

            plot(app.ax1,bins,N,'.','LineWidth',1);
            app.ax1.XScale='linear';
            app.ax1.YScale='log';
            app.ax1.XLim=[-10.^max(log10(abs(bins))),10.^max(log10(abs(bins)))];
            app.ax1.YLim=10.^[floor(log10(min(N(N>0)))),ceil(log10(max(N)))];
            app.ax1.YTick=10.^(floor(log10(min(N(N>0)))):ceil(log10(max(N))));
            app.ax1.XMinorTick='on';
            app.ax1.YMinorTick='on';
            app.ax1.XLabel.Interpreter='tex';
            app.ax1.XLabel.String='Δ\itx\rm (m)';
            app.ax1.YLabel.Interpreter='tex';
            app.ax1.YLabel.String='prob. density (-)';

            tx=text(app.ax1,app.ax1.XLim(1),app.ax1.YLim(2),[' Δ\itt\rm = ' num2str(app.msd(ind,1),'%.2f') ' s']);
            tx.VerticalAlignment='top';
            tx.Interpreter='tex';
            legend(app.ax1,'off');

            app.ax2.Box='on';
            app.ax2.XMinorTick='on';
            app.ax2.YMinorTick='on';
            legend(app.ax2,'off');
            yyaxis(app.ax2,'left');
            ylabel(app.ax2,'\itP\rm(\itD\rm)')
            yyaxis(app.ax2,'right');
            app.ax2.YLim=[0,1];
            app.ax2.YTickLabel=[];
            app.ax2.XLabel.String='log(\itD\rm (m^2/s))';
            tx2=text(app.ax2,app.ax2.XLim(1),app.ax2.YLim(2),[' \Delta\itt\rm = ' sprintf('%.2f',app.msd(ind,1)) ' s']);
            tx2.Interpreter='tex';
            tx2.VerticalAlignment='top';

            app.ax3.Box='on';
            app.ax3.XMinorTick='on';
            app.ax3.YMinorTick='on';
            legend(app.ax3,'off');
            app.ax3.XLabel.String='# Iterations';
            app.ax3.YLabel.String='Total residue';
        end

        % Button pushed function: btnRun
        function btnRunPushed(app, event)
            % reset exit flags
            app.IsStop=false;
            app.IsPaused=false;

            % update UI components
            app.btnRun.Enable='off';
            app.edtNumBins.Editable='off';
            app.btnPause.Enable='on';
            app.btnPause.Text="Pause";

            % determine lagtime index
            [~,l]=min(abs(app.msd(:,1)-app.dt));

            % get the edges colume from the van Hove data and convert to
            % bins
            x=(app.van_Hove{l,2}(1:end-1)+mean(diff(app.van_Hove{l,2}))./2)';

            % bin intervals
            dx=diff(app.van_Hove{l,2});
            dx=dx(:);

            % experimental van_Hove, convert into discrete probability
            phi_tilde=app.van_Hove{l,1}'.*dx;

            % estimated average diffusion coefficient for initial guess
            D_est=app.msd(l,2)./(2.*app.dt);

            % set the range of D for P(D)
            xi_bin=logspace(round(log10(D_est)-3),round(log10(D_est)+3),...
                app.edtNumBins.Value);
            xi_bin=xi_bin(:);
            dxi=diff(xi_bin);
            dxi=dxi(:);
            xi=xi_bin(1:end-1)+dxi./2;
            xi=xi(:);

            % initial guess of P(D)
            psi=(xi./D_est.^2).*exp(-xi.^2./(2.*D_est.^2)).*dxi;
            psi=psi./sum(psi);

            % construct Guassian kernel
            [XI, X] = meshgrid(xi, x);
            P = (1 ./ sqrt(4 .* pi .* XI .* app.dt)) .* exp(-X.^2 ./ (4 .* XI .* app.dt)) .* dx;

            change=[]; % variable to monitor change between iteration
            res=[]; % total residue for plotting
            h=[]; % figure handle
            tol=app.edtTolerance.Value; % tolerance
            iter=0; % iteration counter
            while (isempty(change)||abs(change-tol)>1e-4) && (~app.IsStop) 
                % Lucy's algorithm
                phi=P*psi;
                psi_old=psi;
                psi=psi_old.*(P'*(phi_tilde./phi));
                psi(psi<10e-4)=0;
                psi=psi/sum(psi);

                % residue calculation
                temp=abs((phi_tilde-phi)./phi);
                change=sum(temp,'omitnan');
                res=[res;change];

                % update axes
                hold(app.ax1,'on');
                h=findobj(app.ax1,'Tag','reconstruct');
                if isempty(h)
                    h=plot(app.ax1,x,phi./dx,'-','LineWidth',1,'Tag','reconstruct');
                else
                    h.YData=(phi./dx);
                end
                hold(app.ax1,'off');

                yyaxis(app.ax2,'left');
                area(app.ax2,log10(xi),psi,'LineStyle','none','FaceColor',[0 0.4470 0.7410]);
                yyaxis(app.ax2,'right');
                plot(app.ax2,log10(xi),cumsum(psi),'-','LineWidth',1);
                app.ax2.YLim=[0,1];

                plot(app.ax3,res,'o-');
                hold(app.ax3,'on');
                g=findobj(app.ax3,'Tag','tolerance');
                if isempty(g)
                    g=plot(app.ax3,app.ax3.XLim,[tol,tol],'--','LineWidth',1,'Tag','tolerance');
                else
                    g.XData=app.ax3.XLim;
                    g.YData=[tol,tol];
                end
                hold(app.ax3,'off');

                while app.IsPaused % sink for pausing
                    pause(0.1);
                end

                tol=app.edtTolerance.Value; % in case the user change the tolerance during pausing

                iter=iter+1; % iteration counter
                
                drawnow; % process UI events
            end
            
            % update UI components
            app.btnRun.Enable='on';
            app.edtNumBins.Editable='on';
            app.btnPause.Enable='off';
            app.btnPause.Text="Pause";

            % determine exit condition and update message text
            if app.IsStop
                text_temp=join(["Stopped at ",num2str(iter)," iterations."]);
            else
                text_temp=join(["Tolerance reached at ", num2str(iter)," iterations."]);
            end
            text_temp=join([string(datetime('now')),text_temp]);
            if app.txtMessage.Value==""
                app.txtMessage.Value=text_temp;
            else
                app.txtMessage.Value=[text_temp;app.txtMessage.Value];
            end

            % update final results of iteration
            app.p_D=[xi(:),psi(:)./dxi];
            app.P_D=[xi(:),psi(:)];
            app.van_Hove_rec=[x(:),phi(:)./dx];
            app.dtP=app.dt;
        end

        % Close request function: LucyUIFigure
        function LucyUIFigureCloseRequest(app, event)
            app.callingApp.btnLucy.Enable='on';
            Lucy_result(app.callingApp,app.p_D,app.P_D,app.van_Hove_rec,app.dtP)
            delete(app);
        end

        % Button pushed function: btnStop
        function btnStopButtonPushed(app, event)
            app.IsStop=true;
            app.IsPaused=false;
        end

        % Button pushed function: btnPause
        function btnPausePushed(app, event)
            if app.IsPaused
                app.IsPaused=false;
                app.btnPause.Text="Pause";
            else
                app.IsPaused=true;
                app.btnPause.Text="Resume";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LucyUIFigure = uifigure('Visible', 'off');
            app.LucyUIFigure.Name = 'Lucy''s Algorithm';
            app.LucyUIFigure.CloseRequestFcn = createCallbackFcn(app, @LucyUIFigureCloseRequest, true);
            app.ax1 = uiaxes(app.LucyUIFigure);
            xlabel(app.ax1, 'X')
            ylabel(app.ax1, 'Y')
            zlabel(app.ax1, 'Z')
            app.ax2 = uiaxes(app.LucyUIFigure);
            xlabel(app.ax2, 'X')
            ylabel(app.ax2, 'Y')
            zlabel(app.ax2, 'Z')
            app.ax3 = uiaxes(app.LucyUIFigure);
            xlabel(app.ax3, 'X')
            ylabel(app.ax3, 'Y')
            zlabel(app.ax3, 'Z')
            app.ToleranceLabel = uilabel(app.LucyUIFigure);
            app.ToleranceLabel.Text = 'Tolerance:';
            app.edtTolerance = uieditfield(app.LucyUIFigure, 'numeric');
            app.edtTolerance.Limits = [0 Inf];
            app.edtTolerance.Value = 0.001;
            app.binsEditFieldLabel = uilabel(app.LucyUIFigure);
            app.binsEditFieldLabel.Text = '# bins:';
            app.edtNumBins = uieditfield(app.LucyUIFigure, 'numeric');
            app.edtNumBins.Limits = [100 Inf];
            app.edtNumBins.RoundFractionalValues = 'on';
            app.edtNumBins.ValueDisplayFormat = '%.0f';
            app.edtNumBins.Value = 100;
            app.btnRun = uibutton(app.LucyUIFigure, 'push');
            app.btnRun.ButtonPushedFcn = createCallbackFcn(app, @btnRunPushed, true);
            app.btnRun.Text = 'Run';
            app.btnStop = uibutton(app.LucyUIFigure, 'push');
            app.btnStop.ButtonPushedFcn = createCallbackFcn(app, @btnStopButtonPushed, true);
            app.btnStop.Text = 'Stop';
            app.btnPause = uibutton(app.LucyUIFigure, 'push');
            app.btnPause.ButtonPushedFcn = createCallbackFcn(app, @btnPausePushed, true);
            app.btnPause.Enable = 'off';
            app.btnPause.Text = 'Pause';
            app.txtMessage = uitextarea(app.LucyUIFigure);
            app.txtMessage.Editable = 'off';
            % Show the figure after all components are created
            app.LucyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lucy_dlg(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LucyUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LucyUIFigure)
        end
    end
end