classdef track_anal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        btnTrajectories        matlab.ui.control.Button
        btnGSEROpt             matlab.ui.control.Button
        lblStatus              matlab.ui.control.Label
        lblTrackFiles          matlab.ui.control.Label
        btnSaveButton          matlab.ui.control.Button
        btnRemove              matlab.ui.control.Button
        btnLucy                matlab.ui.control.Button
        sldLagTime             matlab.ui.control.Slider
        LagtimesLabel          matlab.ui.control.Label
        bgpPlotData            matlab.ui.container.ButtonGroup
        btnChi_4               matlab.ui.control.Button
        optChi4                matlab.ui.control.RadioButton
        optVanHove             matlab.ui.control.RadioButton
        optAlpha_2             matlab.ui.control.RadioButton
        optGSER                matlab.ui.control.RadioButton
        optMPMSD               matlab.ui.control.RadioButton
        btnCalculate           matlab.ui.control.Button
        tblCurrentTracks       matlab.ui.control.Table
        btnAddTrack            matlab.ui.control.Button
        pnlParameters          matlab.ui.container.Panel
        edtResolution          matlab.ui.control.NumericEditField
        ResolutionmpixelLabel  matlab.ui.control.Label
        edtShutterTime         matlab.ui.control.NumericEditField
        ShuttertimesLabel      matlab.ui.control.Label
        edtParticleRadius      matlab.ui.control.NumericEditField
        ParticleradiusmLabel   matlab.ui.control.Label
        edtTemperature         matlab.ui.control.NumericEditField
        TemperatureCLabel      matlab.ui.control.Label
        axePlot                matlab.ui.control.UIAxes
    end


    properties (Access = private)
        CurrentPath=pwd % Description
        msd_i
        msd_i_init
        msd
        msd_init
        msd_i_detail
        G
        alpha_2
        alpha_2_i
        van_Hove
        p_D
        P_D
        van_Hove_rec=[]
        dtP
        CurrentSelectedRow % Description
        GSER_dialogBox
        Lucy_dialogBox
        isParallelAvailable
        % chi4_s=[]
        % chi4_c=[]
        % chi4_t=[]
        % F=[]
        chi_cross
        chi_cross_var
        chi_cross_ast
        chi_cross_var_ast
        % blnTrackExcluded
        excludedTrackID=[] % Description
        traj_dialogBox % Description
        tracks
    end

    methods (Access = public)
        function new_tracks(app,tr_list)
            stack = dbstack;
            if length(stack) >= 2
                callerName = stack(2).name;
                allowedMethods = {'track_anal.btnAddTrackButtonPushed',...
                    'track_anal.btnRemoveButtonPushed'};

                if ~any(strcmp(callerName, allowedMethods))
                    error('Property can only be set by authorized methods');
                end
            end
            app.tracks = tr_list;
        end


        function GSER_result(app,G)

            app.G=G;
            if app.bgpPlotData.SelectedObject==app.optGSER
                cla(app.axePlot,'reset');

                app.axePlot.Box='on';

                loglog(app.axePlot,app.G(:,1),app.G(:,2:3),'LineWidth',1);

                app.axePlot.XLabel.String='angular frequency (rad/s)';
                app.axePlot.YLabel.String='Moduli (Pa)';
                app.axePlot.XLim=10.^[floor(log10(min(app.G(:,1)))),ceil(log10(max(app.G(:,1))))];
                app.axePlot.YLim=10.^[floor(log10(min([app.G(app.G(:,2)>0,2);app.G(app.G(:,3)>0,3)]))),...
                    ceil(log10(max([app.G(app.G(:,2)>0,2);app.G(app.G(:,3)>0,3)])))];
                app.axePlot.XTick=10.^(floor(log10(min(app.G(:,1)))):ceil(log10(max(app.G(:,1)))));
                app.axePlot.YTick=10.^(floor(log10(min([app.G(app.G(:,2)>0,2);app.G(app.G(:,3)>0,3)]))):...
                    ceil(log10(max([app.G(app.G(:,2)>0,2);app.G(app.G(:,3)>0,3)]))));
                app.axePlot.XMinorTick='on';
                app.axePlot.YMinorTick='on';

                lg=legend(app.axePlot);

                lg.Interpreter='latex';
                lg.String=[{'$G\prime$'},{'$G\prime\prime$'}];
                lg.Location='best';
                lg.Color='none';


            end
        end

        function Lucy_result(app,p_D,P_D,van_Hove_rec,dtP)
            if ~isempty(p_D)
                app.p_D=p_D;
                app.P_D=P_D;
                app.van_Hove_rec=van_Hove_rec;
                app.dtP=dtP;

                [~,ind2]=min(abs(app.msd(:,1)-app.dtP));
                app.sldLagTime.MinorTicks=ind2;


                if app.bgpPlotData.SelectedObject==app.optVanHove
                    % dt=10.^app.sldLagTime.Value;
                    % [~,ind]=min(abs(app.msd(:,1)-dt));
                    ind=app.sldLagTime.Value;
                    reset(app.axePlot)
                    app.axePlot.Box='on';
                    app.axePlot.XMinorTick='on';
                    app.axePlot.YMinorTick='on';

                    legend(app.axePlot,'off');
                    N=app.van_Hove{ind,1};
                    edges=app.van_Hove{ind,2};
                    bins=edges(1:end-1)+diff(edges)./2;

                    plot(app.axePlot,bins,N,'.','LineWidth',1);
                    app.axePlot.XScale='linear';
                    app.axePlot.YScale='log';
                    hold(app.axePlot,'on');
                    plot(app.axePlot,bins,exp(-bins.^2./(2.*app.msd(ind,2)))./sqrt(2.*pi.*app.msd(ind,2)),'-','LineWidth',1,'Color',[0.8500 0.3250 0.0980])
                    hold(app.axePlot,'off');

                    [~,ind2]=min(abs(app.msd(:,1)-app.dtP));
                    if ~isempty(app.van_Hove_rec)& ind2==ind
                        hold(app.axePlot,'on');
                        plot(app.axePlot,app.van_Hove_rec(:,1),app.van_Hove_rec(:,2),'-','LineWidth',1,'Color',[0.9290 0.6940 0.1250])
                        hold(app.axePlot,'off');
                    end


                    app.axePlot.XLim=[-10.^max(log10(abs(bins))),10.^max(log10(abs(bins)))];
                    app.axePlot.YLim=10.^[floor(log10(min(N(N>0)))),ceil(log10(max(N)))];
                    app.axePlot.YTick=10.^(floor(log10(min(N(N>0)))):ceil(log10(max(N))));
                    app.axePlot.Title.String='van Hove';
                    app.axePlot.XLabel.Interpreter='tex';
                    app.axePlot.XLabel.String='\Delta\itx\rm (m)';
                    app.axePlot.YLabel.Interpreter='tex';
                    app.axePlot.YLabel.String='prob. density (-)';


                    tx=text(app.axePlot,app.axePlot.XLim(1),app.axePlot.YLim(2),[' Δ\itt\rm = ' num2str(app.msd(ind,1),'%.3g') ' s']);
                    tx.VerticalAlignment='top';
                end

            end
        end

        function results = traj_result(app,excludedID)
            excludedID=str2double(excludedID);
            excludedID=excludedID(:);
            app.excludedTrackID=excludedID;
            % app.blnTrackExcluded=true;
            app.btnTrajectories.Enable='on';

        end

        function [lags,chirawmat,chiunbmat,chisingmat,Qmat,varrawmat,varunbmat,biasmat] = ...
                compute_chi_cross_vs_lag_par(app, trlist, deltalist)
            % COMPUTE_CHI_CROSS_VS_LAG_PAR
            %   trlist: cell array of tr matrices [x,y,t,trackID, ...]
            %   deltalist: row vector of delta tolerances

            nFov = numel(trlist);
            S = cell(nFov,1);
            maxlag = 0;

            d_here = local_infer_d_from_trlist(app,trlist);
            if isempty(d_here)
                error('compute_chi_cross_vs_lag_par:EmptyTracks', ...
                    'Could not infer dimension d from trlist.');
            end

            for f = 1:nFov
                if isempty(trlist{f})
                    S{f} = struct('t',{},'r',{});
                    continue;
                end
                tr = cell2mat(trlist{f});
                ids = unique(tr(:,d_here+2));
                T = struct('t',{},'r',{});
                for k = 1:numel(ids)
                    id = ids(k);
                    dat = tr(tr(:,d_here+2)==id, :);
                    [tvec, isrt] = sort(dat(:,d_here+1));
                    rmat = dat(isrt,1:d_here);
                    T(k).t = tvec;
                    T(k).r = rmat;
                    maxlag = max(maxlag, max(tvec)-min(tvec));
                end
                S{f} = T;
            end

            lags = 1:maxlag;
            nLag = numel(lags);
            nDelta = numel(deltalist);

            chirawmat  = nan(nDelta, nLag);
            chiunbmat  = nan(nDelta, nLag);
            chisingmat = nan(nDelta, nLag);
            Qmat       = nan(nDelta, nLag);
            varrawmat  = nan(nDelta, nLag);
            varunbmat  = nan(nDelta, nLag);
            biasmat    = nan(nDelta, nLag);

            % --- Parfor Over Lags ---
            % Calls external function 'compute_chi_cross_fast' directly
            parfor iL = 1:nLag
                dt = lags(iL);

                [chiraw_vec, chiunb_vec, chising_vec, Qsum_vec, ...
                    varraw_vec, varunb_vec, bias_vec] = ...
                    compute_chi_cross_fast(S, dt, deltalist);

                chirawmat(:, iL)  = chiraw_vec;
                chiunbmat(:, iL)  = chiunb_vec;
                chisingmat(:, iL) = chising_vec;
                Qmat(:, iL)       = Qsum_vec;
                varrawmat(:, iL)  = varraw_vec;
                varunbmat(:, iL)  = varunb_vec;
                biasmat(:, iL)    = bias_vec;
            end

            lags = [0, lags];
            chirawmat  = [zeros(nDelta,1), chirawmat];
            chiunbmat  = [zeros(nDelta,1), chiunbmat];
            chisingmat = [zeros(nDelta,1), chisingmat];
            Qmat       = [ones(nDelta,1), Qmat];
            varrawmat  = [zeros(nDelta,1), varrawmat];
            varunbmat  = [zeros(nDelta,1), varunbmat];
            biasmat    = [zeros(nDelta,1), biasmat];

        end

        % =========================================================================
        % HELPER FUNCTIONS
        % =========================================================================

        function d = local_infer_d_from_trlist(app,trlist)
            d = [];
            for f = 1:numel(trlist)
                if isempty(trlist{f}), continue; end
                trf = trlist{f};
                if isempty(trf), continue; end
                if iscell(trf)
                    for k = 1:numel(trf)
                        trk = trf{k};
                        if isempty(trk), continue; end
                        d = size(trk,2) - 2;
                        if d >= 1, return; end
                    end
                elseif isnumeric(trf) && ~isempty(trf)
                    d = size(trf,2) - 2;
                    if d >= 1, return; end
                end
            end
        end


    end

    methods (Access = private)

        function corrected_tracks = drift_canceling(app,tracks)
            % calculate the displacements between adjacent frames, and label them by
            % staring_fr_id's and tr_id's. [dx,dy,starting_fr_id,tr_id]
            drift=cellfun(@(x) [diff(x(:,1:3),1,1),x(1:end-1,3:4)],tracks,'UniformOutput',0);
            drift=cellfun(@(x) x(x(:,3)==1,[1,2,4,5]),drift,'UniformOutput',0);

            % group drift_temp into cell array by starting_fr_id's, and average the
            % dispacements of each starting_fr_id. [mean_dx,mean_dy, starting_fr_id]
            drift=sortrows(cell2mat(drift),[3,4]);
            ind=[[1;find(diff(drift(:,3))~=0)+1],[find(diff(drift(:,3))~=0);length(drift(:,3))]];
            drift=cell2mat(arrayfun(@(i,j) [mean(drift(i:j,1:2),1),drift(i,3)],ind(:,1),ind(:,2),'UniformOutput',0));

            % zero-padding missing starting_fr_id by zero displacements
            padded_drift=zeros(max(drift(:,3))-min(drift(:,3))+1,2);
            step_ids=unique(drift(:,3));
            for i=1:length(step_ids)
                step_id=step_ids(i);
                idx=drift(:,3)==step_id;
                padded_drift(step_id-min(drift(:,3))+1,:)=drift(idx,1:2);
            end
            drift=[padded_drift,(min(drift(:,3)):max(drift(:,3)))'];
            drift(:,1:2)=cumsum(drift(:,1:2),1);
            drift=[[zeros(1,2),min(drift(:,3))-1];drift];
            % now drift can serve as the drift profile
            % drift_profile=drift;

            % If we need to apply corrction using drift
            corrected_tracks=cell(size(tracks));
            for i=1:length(tracks)
                t=tracks{i};
                start_step=t(1,3);
                for j=2:size(t,1)

                    step_id=t(j,3);
                    rel_drift=drift(step_id-min(drift(:,3)),1:2)-drift(start_step-min(drift(:,3)),1:2);
                    t(j,1:2)=t(j,1:2)-rel_drift;
                end

                corrected_tracks{i}=t;
            end

        end


        function [bestRange,bestIdx,M] = maxContinuousOverlap(app,R)
            % Finds the contiguous integer interval [a,b] and subset of R
            % maximizing (length of interval) * (number of vectors covering it).
            %
            % Input:
            %   R       – 1×N cell array, each R{i} is a sorted vector of distinct naturals
            % Outputs:
            %   bestRange – [a b] endpoints of chosen interval
            %   bestIdx   – indices of vectors in R that fully cover a:a:b

            N = numel(R);
            nmax = max(cellfun(@max, R));     % global maximum value

            % build presence matrix M (N×(nmax+1))
            % since the vectors take values from domain [0,nmax], we will
            % maximally need nmax+1 columns
            M = false(N, nmax+1);


            for i = 1:N
                M(i, R{i}+1) = true;            % +1 for MATLAB 1-based
            end

            % row-wise prefix sums for fast range-counts
            P = cumsum(M, 2);

            bestScore = -inf;
            bestRange = [];
            bestIdx   = [];

            for a = 0:nmax
                ia = a+1;
                for b = a:nmax
                    L = b - a + 1;
                    ib = b+1;
                    % which rows cover every value in [a:b]?
                    if ia>1
                        haveAll = (P(:,ib) - P(:,ia-1)) == L;
                    else
                        haveAll = P(:,ib) == L;
                    end
                    c = nnz(haveAll); % number of such rows
                    score = L * c;
                    if score > bestScore
                        bestScore = score;
                        bestRange = [a, b];
                        bestIdx   = find(haveAll);
                    end
                end
            end

            if isempty(bestRange)
                warning('No common interval found.');
            end

        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Check if parallel computing is ready
            v=ver;
            isParallelToolboxInstalled=any(strcmp({v.Name},'Parallel Computing Toolbox'));
            if isParallelToolboxInstalled

                defaultProfile=parallel.defaultClusterProfile;
                if ~isempty(defaultProfile)

                    cluster=parcluster(defaultProfile);

                    try
                        job=createJob(cluster);
                        delete(job);
                        app.isParallelAvailable=true;
                    catch
                        app.isParallelAvailable=false;
                    end
                else
                    app.isParallelAvailable=false;
                end
            else
                app.isParallelAvailable=false;
            end


            % Enable chi_4 calculation if parallel computing is ready
            if app.isParallelAvailable
                app.btnChi_4.Enable='on';
                app.optChi4.Enable='on';
            end


        end

        % Value changed function: edtResolution
        function edtResolutionValueChanged(app, event)
            value = app.edtResolution.Value;
            if isnan(value)||value<=0
                % errordlg('Please enter a positive, numeric value.', 'Bad input', 'modal')
                uialert(app.UIFigure,'Please enter a positive, numeric value.', 'Bad input','Modal',true,'Icon','error')
                app.edtResolution.Value=0.1083266701;
                return
            end
        end

        % Value changed function: edtTemperature
        function edtTemperatureValueChanged(app, event)
            value = app.edtTemperature.Value;
            if isnan(value)||value<=0
                % errordlg('Please enter a positive, numeric value.', 'Bad input', 'modal')
                uialert(app.UIFigure,'Please enter a positive, numeric value.', 'Bad input','Modal',true,'Icon','error')

                app.edtTemperature.Value=30;
                return
            end
        end

        % Value changed function: edtParticleRadius
        function edtParticleRadiusValueChanged(app, event)
            value = app.edtParticleRadius.Value;
            if isnan(value)||value<=0
                % errordlg('Please enter a positive, numeric value.', 'Bad input', 'modal')
                uialert(app.UIFigure,'Please enter a positive, numeric value.', 'Bad input','Modal',true,'Icon','error')
                app.edtParticleRadius.Value=0.1;
                return
            end
        end

        % Value changed function: edtShutterTime
        function edtShutterTimeValueChanged(app, event)
            value = app.edtShutterTime.Value;
            if isnan(value)||value<=0
                % errordlg('Please enter a positive, numeric value.', 'Bad input', 'modal')
                uialert(app.UIFigure,'Please enter a positive, numeric value.', 'Bad input','Modal',true,'Icon','error')
                app.edtShutterTime.Value=0.020142000000000;
                return
            end
        end

        % Button pushed function: btnAddTrack
        function btnAddTrackButtonPushed(app, event)
            [file,path]=uigetfile('*.mat','Select tr.mat file',app.CurrentPath,'MultiSelect','on');

            switch class(file)
                case 'char' % a single file is selected
                    blnSuccess=true;
                    % set the initial state as true and let subsequent errors
                    % change it into false

                    % get a list of variables
                    variable_list=who('-file',fullfile(path,file));

                    % check exisitence of tr
                    if ~isempty(find(ismember(variable_list,'tr'), 1))
                        tr=load(fullfile(path,file),'tr');

                    else
                        blnSuccess=false;
                        uialert(app.UIFigure,'No track data in this file.','Icon','error','Modal',true);
                        figure(app.UIFigure);
                    end

                    if blnSuccess

                        t=app.tblCurrentTracks.Data;
                        tr=tr.tr;
                        tr=sortrows(tr,[4,3]);

                        N_tracks=tr(end,4);

                        if isempty(t)
                            t={path,file,num2str(N_tracks)};
                            app.tblCurrentTracks.ColumnName={'Path';'File name';'# tracks'};

                        else
                            t=[t;{path,file,num2str(N_tracks)}];

                            % check duplicated opened file
                            dup=t;
                            [~,idx]=unique(strcat(dup(:,1),dup(:,2),dup(:,3)));
                            t=dup(idx,:);
                        end
                        app.tblCurrentTracks.Data=t;
                        app.CurrentPath=path;

                        app.excludedTrackID=[];
                        app.msd=[];
                    end

                    % load other parameters if exists
                    if ~isempty(find(ismember(variable_list,'dt'), 1))
                        dt=load(fullfile(path,file),'dt');
                        if ~isempty(dt.dt)
                            app.edtShutterTime.Value=dt.dt;
                        end
                    end

                    if ~isempty(find(ismember(variable_list,'PixelSize'), 1))

                        PixelSize=load(fullfile(path,file),'PixelSize');
                        if ~isempty(PixelSize.PixelSize)
                            app.edtResolution.Value=PixelSize.PixelSize;
                        end
                    end


                case 'cell' % multiple files are selected

                    no_track_list=[];
                    for i=1:length(file)
                        blnSuccess=true; % initial value of blnSuccess. If nothing bad happens it remains true

                        % check the variable in the .mat files for matrix tr
                        variable_list=who('-file',fullfile(path,file{i}));
                        if ~isempty(find(ismember(variable_list,'tr'), 1))
                            % load the tr matrix to current workspace
                            tr=load(fullfile(path,file{i}),'tr');
                        else
                            blnSuccess=false;
                            % keep track with the .mat files without the needed variable
                            no_track_list=[no_track_list;fullfile(path,file{i})];
                        end

                        if blnSuccess

                            % prepare for info to be displayed in
                            % tblCurrentTracks
                            t=app.tblCurrentTracks.Data;
                            tr=tr.tr;
                            tr=sortrows(tr,[4,3]);
                            N_tracks=tr(end,4);

                            if isempty(t)
                                t={path,file{i},num2str(N_tracks)};
                                app.tblCurrentTracks.ColumnName={'Path';'File name';'# tracks'};

                            else
                                t=[t;{path,file{i},num2str(N_tracks)}];

                                % check duplicated opened file
                                dup=t;
                                [~,idx]=unique(strcat(dup(:,1),dup(:,2),dup(:,3)));
                                t=dup(idx,:);
                            end
                            app.tblCurrentTracks.Data=t;


                            % refresh value of blnTrackExcluded each time
                            % new track files are loaded
                            app.excludedTrackID=[];
                            app.msd=[];

                        end
                        app.CurrentPath=path;

                        % load other parameters if exists
                        % lag time
                        if ~isempty(find(ismember(variable_list,'dt'), 1))
                            dt=load(fullfile(path,file{i}),'dt');
                            app.edtShutterTime.Value=dt.dt;
                        end

                        % pixel size
                        if ~isempty(find(ismember(variable_list,'PixelSize'), 1))
                            PixelSize=load(fullfile(path,file{i}),'PixelSize');
                            if ~isempty(PixelSize.PixelSize)

                                app.edtResolution.Value=PixelSize.PixelSize;
                            end
                        end


                    end
                    if ~isempty(no_track_list)
                        message=["No track data in file(s):";no_track_list];
                        uialert(app.UIFigure,message,'Error','Icon','error','Modal',true);
                        figure(app.UIFigure);
                    end
                case 'double' % no file is selected
                    uialert(app.UIFigure,'No file selected.','Canceled','Icon','info','Modal',true);
                    figure(app.UIFigure)
                    blnSuccess=false;
                    % app.CurrentPath=path;
            end

            %% Prepare for app.tracks, the globle unexcluded track list
            if blnSuccess
                tt=app.tblCurrentTracks.Data;

                if ~isempty(tt)

                    [N,~]=size(tt); % N is the number of track files
                    tr_list=cell(N,1); % pre-allocate
                    lastNTrack=0;
                    for l=1:N
                        tr=load([tt{l,1} tt{l,2}],'tr');
                        tr=tr.tr;

                        % regard multiple track file as one with extra tracks.
                        % effectively adding to the tr_id's.
                        tr(:,4)=tr(:,4)+lastNTrack;
                        tr=sortrows(tr,[4,3]);

                        % separate by tracks into cell array |tr_list|
                        ind=[[1;find(diff(tr(:,4))~=0)+1],[find(diff(tr(:,4))~=0);length(tr(:,4))]];
                        tr_temp=cell(length(ind(:,1)),1);
                        for i=1:length(ind(:,1))
                            tr_temp{i}=tr(ind(i,1):ind(i,2),:);
                        end % i

                        % if length(tr_temp)>1
                        %     tr_temp=drift_canceling(app,tr_temp);
                        % end

                        tr_list{l}=tr_temp;

                        lastNTrack=lastNTrack+length(tr_temp);
                    end
                    % app.tracks=tr_list;
                    % app.new_tracks(app,tr_list);
                    new_tracks(app,tr_list);
                end
            end


            figure(app.UIFigure);


        end

        % Button pushed function: btnCalculate
        function btnCalculateButtonPushed(app, event)

            blnFirstCalculate=isempty(app.msd);

            % set chi_cross data to empty
            app.chi_cross=[];


            % allocate final data variablesual
            app.msd=[];
            app.msd_i=[];
            app.G=[];
            app.alpha_2=[];
            app.alpha_2_i=[];
            app.van_Hove=[];
            app.p_D=[];
            app.P_D=[];
            app.van_Hove_rec=[];
            app.dtP=[];


            % read raw data and parameters
            dt=app.edtShutterTime.Value;
            res=app.edtResolution.Value*1e-6;
            T=app.edtTemperature.Value;
            a=app.edtParticleRadius.Value*1e-6;


            tt=app.tblCurrentTracks.Data;
            app.lblStatus.Interpreter='none';
            if isempty(tt)
                uialert(app.UIFigure,'No tracks added.','Blank data','Icon','error','Modal',true)
                % errordlg('No tracks added.','Blank data','modal')
                return
            else
                tr_list=app.tracks;
                N=length(tr_list);
            end

            % drawnow


            % tracks_exclusion;
            if ~isempty(app.excludedTrackID)
                % app.blnTrackExcluded
                tr_list=cell(size(app.tracks));
                N=length(app.tracks); % N is the number of track files
                for l=1:N
                    tr_temp=app.tracks{l};
                    excluded_rows=false(length(tr_temp),1);
                    for j=1:length(tr_temp)
                        t=tr_temp{j};
                        if ismember(t(1,4),app.excludedTrackID)
                            excluded_rows(j)=true;
                        end
                    end
                    tr_temp(excluded_rows)=[];
                    tr_list{l}=tr_temp;


                end
                % tr_list=app.tracks;
            end


            % N_tracks_per_tr=arrayfun(@(i) length(tr_list{i}),(1:length(tr_list))','UniformOutput',1);
            %
            % dxy=cell(sum(N_tracks_per_tr),1);
            % N_tracks_proc=0;
            % for l=1:N % loop through number of track files
            %     tr_temp=tr_list{l};
            %     % tr_temp have been separated into different tr_id's
            %
            %
            %
            %     dxy_temp=cell(length(tr_temp),1);
            %
            %     for j=1:length(tr_temp)
            %         % loop through number of tracks under the given track
            %         % file
            %         t=tr_temp{j};
            %
            %
            %         % lag-time-dependent analysis
            %         [p,q]=meshgrid(1:length(t(:,1)),1:length(t(:,1)));
            %         pairs=[p(:),q(:)];
            %         pairs(pairs(:,1)>=pairs(:,2),:)=[];
            %
            %         if ~isempty(pairs)
            %             % One cannot calculate 'displacement' out of one
            %             % position. Tracks with only one point produce
            %             % empty |pairs|, and hence are omitted.
            %
            %             t=sortrows([t(pairs(:,2),1)-t(pairs(:,1),1),t(pairs(:,2),2)-t(pairs(:,1),2),...
            %                 t(pairs(:,2),3)-t(pairs(:,1),3),ones(length(pairs(:,1)),1).*t(1,4)],[3,4]);
            %             % now |t| contains [dx,dy,fr_lag, tr_id]
            %
            %             % separate |t| by fr_lag into cell array |dxy_temp|
            %             ind=[[1;find(diff(t(:,3))~=0)+1],[find(diff(t(:,3))~=0);length(t(:,3))]];
            %             temp_cell=cell(length(ind(:,1)),1);
            %             for k=1:length(ind(:,1))
            %                 temp_cell{k}=t(ind(k,1):ind(k,2),:);
            %             end % k
            %             dxy_temp{j}=temp_cell;
            %
            %
            %             % N_tracks_proc=N_tracks_proc+1;
            %             track_id_proc=t(1,4);
            %             app.lblStatus.Text=['tr#' num2str(track_id_proc)];
            %             drawnow
            %         end
            %     end %j
            %     dxy(sum(N_tracks_per_tr(1:l-1))+1:sum(N_tracks_per_tr(1:l)))=dxy_temp;
            %
            %     % tr_list{l}=tr_temp;
            % end %l
            % % app.tracks=tr_list;
            %
            % % clean up the empty cells in dxy due to pre-allocation for
            % % un-processed tracks.
            % ind=cellfun(@(x) ~isempty(x),dxy,'UniformOutput',1);
            % dxy=dxy(ind);
            %
            % clearvars dxy_temp tr_list tr_temp temp_cell t tr p q pairs drift_temp;
            %
            % app.lblStatus.Text='msd_i';
            % drawnow
            % % single-particle msd
            % raw_msd_i=cell(length(dxy),1);
            % for i=1:length(dxy) % loop through total number of tracks
            %     temp=zeros(length(dxy{i}),3); % preallocate
            %     for j=1:length(dxy{i})
            %         % calculate dr^2=dx^2+dy^2
            %         temp(j,:)=[dxy{i}{j}(1,3),mean(sum(dxy{i}{j}(:,1:2).^2,2)),dxy{i}{j}(1,4)]; % msd_i of dx
            %     end
            %     raw_msd_i{i}=temp;
            % end % i
            % % now each cell of |raw_msd_i| contains [fr_lag,msd_i,tr_id] of a
            % % track. unit of msd: px^2
            %
            % % unit conversion
            % app.msd_i=cellfun(@(x) [x(:,1).*dt,x(:,2).*res.^2,x(:,3)],raw_msd_i,'UniformOutput',0);
            % clearvars raw_msd_i;
            % if blnFirstCalculate
            %     app.msd_i_init=app.msd_i;
            % end
            %
            %
            %
            % app.lblStatus.Text='alpha_2_i';
            % drawnow
            % % single-particle alpha_2
            % raw_alpha_2_i=cell(length(dxy),1);
            % for i=1:length(dxy)
            %     temp=zeros(length(dxy{i}),4);
            %     for j=1:length(dxy{i})
            %         Nk=length(dxy{i}{j}(:,1));
            %         temp(j,:)=[dxy{i}{j}(1,3),kurtosis(dxy{i}{j}(:,1),0,1)-3,...
            %             24.*Nk.*(Nk-1).^2./((Nk+3).*(Nk+5).*(Nk-2).*(Nk-3)),...
            %             dxy{i}{j}(1,4)]; % alpha_2_i from dx only
            %     end
            %     raw_alpha_2_i{i}=temp(~isnan(temp(:,2)),:);
            % end
            %
            % % separate by fr_lag into cell array ...
            % raw_alpha_2_i=sortrows(cell2mat(raw_alpha_2_i),[1,4]);
            % ind=[[1;find(diff(raw_alpha_2_i(:,1))~=0)+1],[find(diff(raw_alpha_2_i(:,1))~=0);length(raw_alpha_2_i(:,1))]];
            %
            % % ... and average all single-particle alpha_2 and record their std_err
            % app.alpha_2_i=cell2mat(arrayfun(@(i,j) [raw_alpha_2_i(i,1).*dt mean(raw_alpha_2_i(i:j,2)),std(raw_alpha_2_i(i:j,2))],...
            %     ind(:,1),ind(:,2),'UniformOutput',0));
            % clearvars raw_alpha_2_i;
            %
            %
            % app.lblStatus.Text='msd';
            % drawnow
            %
            % % combine displacement of different particles
            % dxy=sortrows(cell2mat(cellfun(@(x) cell2mat(cellfun(@(y) y ,x,'UniformOutput',0)),dxy,'UniformOutput',0)),[3,4]);
            %
            % % separate by fr_lag into cell array ...
            % ind=[[1;find(diff(dxy(:,3))~=0)+1],[find(diff(dxy(:,3))~=0);length(dxy(:,3))]];
            %
            % % and calculate dx^2, dy^2 and dr^2
            % raw_msd=cell2mat(arrayfun(@(i,j) [dxy(i,3),mean(dxy(i:j,1).^2),...
            %     mean(dxy(i:j,2).^2), mean(sum(dxy(i:j,1:2).^2,2))],ind(:,1),ind(:,2),'UniformOutput',0));
            % app.msd=[raw_msd(:,1).*dt,raw_msd(:,2:4).*res.^2]; % unit conversion
            % clearvars raw_msd;
            % if blnFirstCalculate
            %     app.msd_init=app.msd;
            % end
            %
            %
            % app.lblStatus.Text='alpha_2';
            % drawnow
            %
            % % calculate alpha_2 only for dx
            % raw_alpha_2=cell2mat(arrayfun(@(i,j) [dxy(i,3).*dt,kurtosis(dxy(i:j,1),0,1)-3],ind(:,1),ind(:,2),'UniformOutput',0));
            % Nk=ind(:,2)-ind(:,1)+1;
            % app.alpha_2=[raw_alpha_2,abs(sqrt(24.*Nk.*(Nk-1).^2./((Nk+3).*(Nk+5).*(Nk-2).*(Nk-3))))];
            % clearvars raw_alpha_2;
            %
            % app.lblStatus.Text='van_Hove';
            % drawnow
            % % histogram dx only
            % [N,edges]=arrayfun(@(i,j) histcounts(dxy(i:j,1).*res,100,'Normalization','pdf'),ind(:,1),ind(:,2),'UniformOutput',0);
            % app.van_Hove=[N,edges];
            %
            %
            % app.lblStatus.Text='Done!';
            % drawnow
            % clearvars dxy;

            %% ============================================================
            %  Fast exact-support displacement build + MSD + bias-corrected alpha2
            %  - NO jackknife
            %  - Exact-support variance for both SP and MP alpha_2
            %  - Uses exact positive-offset terms to apply B_{i,n} correction
            %% ============================================================

            % ---- non-Gaussian \alpha_2 parameters (manuscript) ----
            d = 2;                % assumes 2D
            K = (d + 2) / d;

            % ============================================================
            % 1) Build dxy per track per fr_lag (keep start_step for exact-support)
            % ============================================================
            N_tracks_per_tr = arrayfun(@(i) length(tr_list{i}), (1:length(tr_list))', 'UniformOutput', 1);

            dxy = cell(sum(N_tracks_per_tr), 1);

            for l = 1:N
                tr_temp = tr_list{l};
                dxy_temp = cell(length(tr_temp), 1);

                for j = 1:length(tr_temp)
                    t = tr_temp{j};   % expected: [x, y, step_id, track_id]
                    if isempty(t) || size(t,2) < 4
                        continue;
                    end
                    mObs = length(t(:,1));
                    if mObs < 2
                        continue;
                    end

                    [p,qidx] = meshgrid(1:mObs, 1:mObs);
                    pairs = [p(:), qidx(:)];
                    pairs(pairs(:,1) >= pairs(:,2), :) = [];

                    if isempty(pairs)
                        continue;
                    end

                    dx = t(pairs(:,2),1) - t(pairs(:,1),1);
                    dy = t(pairs(:,2),2) - t(pairs(:,1),2);
                    fr_lag = t(pairs(:,2),3) - t(pairs(:,1),3);
                    start_step = t(pairs(:,1),3);
                    tr_id = ones(length(fr_lag),1) .* t(1,4);

                    tp = [dx, dy, fr_lag, tr_id, start_step];
                    tp = sortrows(tp, [3,5]); % sort by fr_lag then start_step

                    ind = [ [1; find(diff(tp(:,3)) ~= 0) + 1], [find(diff(tp(:,3)) ~= 0); length(tp(:,3))] ];

                    temp_cell = cell(length(ind(:,1)), 1);
                    for k = 1:length(ind(:,1))
                        temp_cell{k} = tp(ind(k,1):ind(k,2), :);
                    end
                    dxy_temp{j} = temp_cell;

                    track_id_proc = tp(1,4);
                    app.lblStatus.Text = ['tr#' num2str(track_id_proc)];
                    drawnow
                end
                dxy(sum(N_tracks_per_tr(1:l-1))+1 : sum(N_tracks_per_tr(1:l))) = dxy_temp;
            end

            ind = cellfun(@(x) ~isempty(x), dxy, 'UniformOutput', 1);
            dxy = dxy(ind);
            clearvars dxy_temp tr_list tr_temp t tr p q pairs tp fr_lag start_step dx dy ind;

            nTracks = length(dxy);

            maxLag = 0;
            for i = 1:nTracks
                if isempty(dxy{i}), continue; end
                frs = cellfun(@(c) c(1,3), dxy{i});
                maxLag = max(maxLag, max(frs));
            end
            lag_frames_all = (1:maxLag)';
            lag_time_all   = lag_frames_all * dt;

            % ============================================================
            % 2) msd_i (unchanged form)
            % ============================================================
            app.lblStatus.Text = 'msd_i';
            drawnow

            raw_msd_i = cell(nTracks,1);
            for i = 1:nTracks
                temp = zeros(length(dxy{i}), 3);
                for j = 1:length(dxy{i})
                    grp = dxy{i}{j};
                    temp(j,:) = [grp(1,3), mean(sum(grp(:,1:2).^2, 2)), grp(1,4)];
                end
                raw_msd_i{i} = temp;
            end
            app.msd_i = cellfun(@(x) [x(:,1).*dt, x(:,2).*res.^2, x(:,3)], raw_msd_i, 'UniformOutput', 0);
            clearvars raw_msd_i;
            if blnFirstCalculate
                app.msd_i_init = app.msd_i;
            end

            % ============================================================
            % 3) msd pooled + van_Hove (naive weighted & fast)
            % ============================================================
            app.lblStatus.Text = 'msd';
            drawnow

            dxy_flat = cell2mat(cellfun(@(x) cell2mat(cellfun(@(y) y, x, 'UniformOutput',0)), dxy, 'UniformOutput',0));
            dxy_flat = sortrows(dxy_flat, [3,4]);
            ind = [ [1; find(diff(dxy_flat(:,3)) ~= 0) + 1], [find(diff(dxy_flat(:,3)) ~= 0); length(dxy_flat(:,3))] ];

            raw_msd = cell2mat(arrayfun(@(i1,i2) ...
                [dxy_flat(i1,3), mean(dxy_flat(i1:i2,1).^2), mean(dxy_flat(i1:i2,2).^2), mean(sum(dxy_flat(i1:i2,1:2).^2,2))], ...
                ind(:,1), ind(:,2), 'UniformOutput',0));

            app.msd = [raw_msd(:,1).*dt, raw_msd(:,2:4).*res.^2];
            clearvars raw_msd dxy_flat ind;
            if blnFirstCalculate
                app.msd_init = app.msd;
            end

            app.lblStatus.Text = 'van_Hove';
            drawnow

            dxy_disp = cell2mat(cellfun(@(x) cell2mat(cellfun(@(y) y, x, 'UniformOutput',0)), dxy, 'UniformOutput',0));
            dxy_disp = sortrows(dxy_disp, [3,4]);
            ind = [ [1; find(diff(dxy_disp(:,3))~=0)+1], [find(diff(dxy_disp(:,3))~=0); length(dxy_disp(:,3))] ];
            [Nvh,edgesCell] = arrayfun(@(i,j) histcounts(dxy_disp(i:j,1).*res, 100, 'Normalization','pdf'), ...
                ind(:,1), ind(:,2), 'UniformOutput', 0);
            app.van_Hove = [Nvh, edgesCell];
            clearvars dxy_disp ind Nvh edgesCell

            % ============================================================
            % 4) alpha_2 parfor gathering exact variances & offsets
            % ============================================================
            app.lblStatus.Text = 'alpha_2_i';
            drawnow

            results = cell(nTracks, 1);

            parfor i = 1:nTracks
                q_loc          = NaN(1, maxLag);
                X_loc          = NaN(1, maxLag);
                Y_loc          = NaN(1, maxLag);
                V0_xx_loc      = NaN(1, maxLag);
                V0_yy_loc      = NaN(1, maxLag);
                V0_xy_loc      = NaN(1, maxLag);
                Vcorr_xx_loc   = NaN(1, maxLag);
                Vcorr_yy_loc   = NaN(1, maxLag);
                Vcorr_xy_loc   = NaN(1, maxLag);

                lagcells = dxy{i};
                if isempty(lagcells)
                    results{i} = {q_loc, X_loc, Y_loc, V0_xx_loc, V0_yy_loc, V0_xy_loc, Vcorr_xx_loc, Vcorr_yy_loc, Vcorr_xy_loc};
                    continue;
                end

                for j = 1:numel(lagcells)
                    grp = lagcells{j};
                    nLag = grp(1,3);
                    Nk   = size(grp,1);

                    if ~isfinite(nLag) || nLag < 1 || nLag > maxLag || Nk < 2
                        continue;
                    end
                    if size(grp,2) < 5
                        continue;
                    end

                    r2 = sum(grp(:,1:2).^2, 2);
                    if any(~isfinite(r2))
                        continue;
                    end

                    X = mean(r2);
                    Y = mean(r2.^2);
                    if ~isfinite(X) || ~isfinite(Y) || X <= 0
                        continue;
                    end

                    start_steps = grp(:,5);

                    % ============================================================
                    % INLINED: Exact-support & excess-bias covariance calculations
                    % No external App function calls -> perfectly parfor/App-safe
                    % ============================================================
                    q_samp = numel(r2);
                    [start_steps_srt, ord] = sort(start_steps);
                    x_srt = r2(ord);
                    y_srt = r2(ord).^2;

                    zx = x_srt - X;
                    zy = y_srt - Y;

                    % Zero-offset terms
                    V0_xx_val = max(mean(zx.^2) / q_samp, 0);
                    V0_yy_val = max(mean(zy.^2) / q_samp, 0);
                    V0_xy_val = mean(zx .* zy) / q_samp;

                    len_h = start_steps_srt(end) - start_steps_srt(1) + 1;
                    q2_samp = q_samp * q_samp;

                    Vcorr_xx_val = 0;
                    Vcorr_yy_val = 0;
                    Vcorr_xy_val = 0;

                    if len_h < 50 * q_samp
                        % Ultra-fast exact positive-offset via FFT-based conv
                        Xf = zeros(len_h, 1);
                        Yf = zeros(len_h, 1);
                        Mf = zeros(len_h, 1);
                        idx_fast = start_steps_srt - start_steps_srt(1) + 1;
                        Xf(idx_fast) = x_srt;
                        Yf(idx_fast) = y_srt;
                        Mf(idx_fast) = 1;

                        m_h_all = round(conv(Mf, flip(Mf)));
                        pos_idx = len_h + 1 : 2*len_h - 1;
                        valid = m_h_all(pos_idx) > 0;

                        if any(valid)
                            pos_idx = pos_idx(valid);
                            m_h = m_h_all(pos_idx);

                            sumBx = conv(Xf, flip(Mf)); sumBx = sumBx(pos_idx);
                            sumAx = conv(Mf, flip(Xf)); sumAx = sumAx(pos_idx);
                            sumBy = conv(Yf, flip(Mf)); sumBy = sumBy(pos_idx);
                            sumAy = conv(Mf, flip(Yf)); sumAy = sumAy(pos_idx);

                            gxx = conv(Xf, flip(Xf)); gxx = gxx(pos_idx);
                            gyy = conv(Yf, flip(Yf)); gyy = gyy(pos_idx);
                            gxy = conv(Yf, flip(Xf)); gxy = gxy(pos_idx);
                            gyx = conv(Xf, flip(Yf)); gyx = gyx(pos_idx);

                            meanAx = sumAx ./ m_h; meanBx = sumBx ./ m_h;
                            meanAy = sumAy ./ m_h; meanBy = sumBy ./ m_h;

                            Gxx_arr = (gxx ./ m_h) - meanAx .* meanBx;
                            Gyy_arr = (gyy ./ m_h) - meanAy .* meanBy;
                            Gxy_arr = (gxy ./ m_h) - meanAx .* meanBy;
                            Gyx_arr = (gyx ./ m_h) - meanAy .* meanBx;

                            Vcorr_xx_val = sum((2 .* m_h ./ q2_samp) .* Gxx_arr);
                            Vcorr_yy_val = sum((2 .* m_h ./ q2_samp) .* Gyy_arr);
                            Vcorr_xy_val = sum((m_h ./ q2_samp) .* (Gxy_arr + Gyx_arr));
                        end
                    else
                        % Generic fallback path for sparse segments
                        offsets = unique(start_steps_srt - start_steps_srt');
                        offsets = offsets(offsets > 0);

                        for kk = 1:numel(offsets)
                            h_off = offsets(kk);
                            [tf, loc] = ismember(start_steps_srt + h_off, start_steps_srt);
                            idxA = find(tf);
                            idxB = loc(tf);

                            m_h = numel(idxA);
                            if m_h == 0, continue; end

                            Ax = x_srt(idxA);  Bx = x_srt(idxB);
                            Ay = y_srt(idxA);  By = y_srt(idxB);

                            meanAx = sum(Ax)/m_h; meanBx = sum(Bx)/m_h;
                            meanAy = sum(Ay)/m_h; meanBy = sum(By)/m_h;

                            Gxx_arr = sum(Ax .* Bx)/m_h - meanAx*meanBx;
                            Gyy_arr = sum(Ay .* By)/m_h - meanAy*meanBy;
                            Gxy_arr = sum(Ax .* By)/m_h - meanAx*meanBy;
                            Gyx_arr = sum(Ay .* Bx)/m_h - meanAy*meanBx;

                            Vcorr_xx_val = Vcorr_xx_val + (2*m_h/q2_samp) * Gxx_arr;
                            Vcorr_yy_val = Vcorr_yy_val + (2*m_h/q2_samp) * Gyy_arr;
                            Vcorr_xy_val = Vcorr_xy_val + (m_h/q2_samp) * (Gxy_arr + Gyx_arr);
                        end
                    end
                    % ============================================================

                    if any(~isfinite([V0_xx_val, V0_yy_val, V0_xy_val])) || any(~isfinite([Vcorr_xx_val, Vcorr_yy_val, Vcorr_xy_val]))
                        continue;
                    end

                    q_loc(nLag)          = Nk;
                    X_loc(nLag)          = X;
                    Y_loc(nLag)          = Y;
                    V0_xx_loc(nLag)      = V0_xx_val;
                    V0_yy_loc(nLag)      = V0_yy_val;
                    V0_xy_loc(nLag)      = V0_xy_val;
                    Vcorr_xx_loc(nLag)   = Vcorr_xx_val;
                    Vcorr_yy_loc(nLag)   = Vcorr_yy_val;
                    Vcorr_xy_loc(nLag)   = Vcorr_xy_val;
                end

                results{i} = {q_loc, X_loc, Y_loc, V0_xx_loc, V0_yy_loc, V0_xy_loc, Vcorr_xx_loc, Vcorr_yy_loc, Vcorr_xy_loc};
            end

            % unpack after parfor
            q_mat          = NaN(nTracks, maxLag);
            X_mat          = NaN(nTracks, maxLag);
            Y_mat          = NaN(nTracks, maxLag);
            V0_xx_mat      = NaN(nTracks, maxLag);
            V0_yy_mat      = NaN(nTracks, maxLag);
            V0_xy_mat      = NaN(nTracks, maxLag);
            Vcorr_xx_mat   = NaN(nTracks, maxLag);
            Vcorr_yy_mat   = NaN(nTracks, maxLag);
            Vcorr_xy_mat   = NaN(nTracks, maxLag);

            for i = 1:nTracks
                tmp = results{i};
                q_mat(i,:)          = tmp{1};
                X_mat(i,:)          = tmp{2};
                Y_mat(i,:)          = tmp{3};
                V0_xx_mat(i,:)      = tmp{4};
                V0_yy_mat(i,:)      = tmp{5};
                V0_xy_mat(i,:)      = tmp{6};
                Vcorr_xx_mat(i,:)   = tmp{7};
                Vcorr_yy_mat(i,:)   = tmp{8};
                Vcorr_xy_mat(i,:)   = tmp{9};
            end

            % ============================================================
            % 5) Compute bias-corrected alpha2_sp and alpha2_mp and variances
            % ============================================================
            app.lblStatus.Text = 'alpha_2';
            drawnow

            alpha2_sp_arr = NaN(maxLag,1);
            se_sp_arr     = NaN(maxLag,1);

            alpha2_mp_arr = NaN(maxLag,1);
            se_mp_arr     = NaN(maxLag,1);

            for nLag = 1:maxLag
                qcol = q_mat(:,nLag);
                Xcol = X_mat(:,nLag);
                Ycol = Y_mat(:,nLag);

                elig = isfinite(qcol) & (qcol >= 2) & isfinite(Xcol) & (Xcol > 0);
                if ~any(elig), continue; end

                q_e = qcol(elig);
                Q   = sum(q_e);
                if ~(isfinite(Q) && Q > 0), continue; end
                w = q_e / Q;  % weights proportional to sample size

                Xc = Xcol(elig);
                Yc = Ycol(elig);

                V0xx_c = V0_xx_mat(elig, nLag);
                V0yy_c = V0_yy_mat(elig, nLag);
                V0xy_c = V0_xy_mat(elig, nLag);

                Vcorr_xx_c = Vcorr_xx_mat(elig, nLag);
                Vcorr_yy_c = Vcorr_yy_mat(elig, nLag);
                Vcorr_xy_c = Vcorr_xy_mat(elig, nLag);

                Vfull_xx_c = V0xx_c + Vcorr_xx_c;
                Vfull_yy_c = V0yy_c + Vcorr_yy_c;
                Vfull_xy_c = V0xy_c + Vcorr_xy_c;

                % --- Single-Particle (SP) Estimator ---
                cNorm_sp_c = (q_e .* d + 2) ./ (q_e .* d);
                aRaw_sp_c  = Yc ./ (K .* Xc.^2) - 1;

                % Track-level excess h>0 bias correction applied directly to each track
                B_sp_c = (3 .* Yc ./ (K .* Xc.^4)) .* Vcorr_xx_c - (2 ./ (K .* Xc.^3)) .* Vcorr_xy_c;
                aCorr_sp_c = cNorm_sp_c .* (aRaw_sp_c + 1) - 1 - cNorm_sp_c .* B_sp_c;

                % Exact track-level SP variances for Delta Method
                fx_sp = -2 .* cNorm_sp_c .* Yc ./ (K .* Xc.^3);
                fy_sp = cNorm_sp_c ./ (K .* Xc.^2);

                varA_sp_c = (fx_sp.^2) .* Vfull_xx_c + (fy_sp.^2) .* Vfull_yy_c + 2 .* fx_sp .* fy_sp .* Vfull_xy_c;

                alpha2_sp_arr(nLag) = sum(w .* aCorr_sp_c);
                var_sp_total = sum((w.^2) .* varA_sp_c);
                se_sp_arr(nLag) = sqrt(max(real(var_sp_total), 0));

                % --- Multi-Particle (MP) Estimator ---
                X_mp = sum(w .* Xc);
                Y_mp = sum(w .* Yc);

                if X_mp <= 0 || ~isfinite(X_mp) || ~isfinite(Y_mp), continue; end

                cNorm_mp = (Q * d + 2) / (Q * d);
                aRaw_mp = Y_mp / (K * X_mp^2) - 1;

                Vcorr_xx_mp = sum((w.^2) .* Vcorr_xx_c);
                Vcorr_yy_mp = sum((w.^2) .* Vcorr_yy_c);
                Vcorr_xy_mp = sum((w.^2) .* Vcorr_xy_c);

                Vfull_xx_mp = sum((w.^2) .* Vfull_xx_c);
                Vfull_yy_mp = sum((w.^2) .* Vfull_yy_c);
                Vfull_xy_mp = sum((w.^2) .* Vfull_xy_c);

                % Pooled excess h>0 bias correction applied dynamically
                B_mp = (3 * Y_mp / (K * X_mp^4)) * Vcorr_xx_mp - (2 / (K * X_mp^3)) * Vcorr_xy_mp;
                aCorr_mp = cNorm_mp * (aRaw_mp + 1) - 1 - cNorm_mp * B_mp;

                fx_mp = -2 * cNorm_mp * Y_mp / (K * X_mp^3);
                fy_mp = cNorm_mp / (K * X_mp^2);

                varA_mp = (fx_mp^2) * Vfull_xx_mp + (fy_mp^2) * Vfull_yy_mp + 2 * fx_mp * fy_mp * Vfull_xy_mp;

                alpha2_mp_arr(nLag) = aCorr_mp;
                se_mp_arr(nLag) = sqrt(max(real(varA_mp), 0));
            end

            % Keep App variables identical to original setup constraints
            app.alpha_2_i = [lag_time_all, alpha2_sp_arr, se_sp_arr];
            app.alpha_2   = [lag_time_all, alpha2_mp_arr, se_mp_arr];

            app.lblStatus.Text = 'Done!';
            drawnow

            clearvars q_mat X_mat Y_mat V0_xx_mat V0_yy_mat V0_xy_mat Vcorr_xx_mat Vcorr_yy_mat Vcorr_xy_mat results


            % calculate G' and G''
            app.btnGSEROpt.Enable='off';
            T=app.edtTemperature.Value;
            a=app.edtParticleRadius.Value*1e-6;

            app.GSER_dialogBox=GSER_dlg(app,app.msd,T,a);


            % update slider
            app.sldLagTime.Limits=[1,length(app.msd(:,1))];


            % app.sldLagTime.Limits=log10([min(app.msd(:,1)),max(app.msd(:,1))]);
            % decade_points=ceil(log10(min(app.msd(:,1)))):floor(log10(max(app.msd(:,1))));
            % app.sldLagTime.MajorTicks=decade_points;
            % app.sldLagTime.MajorTickLabels=arrayfun(@(i) num2str(10^decade_points(i)),1:length(decade_points),'UniformOutput',0);
            % app.sldLagTime.MinorTicks=floor(log10(min(app.msd(:,1)))/0.2)*0.2:0.2:ceil(log10(max(app.msd(:,1)))/0.2)*0.2;
            % app.sldLagTime.Value=log10(min(app.msd(:,1)));
            app.sldLagTime.Value=1;
            app.sldLagTime.Step=1;
            app.sldLagTime.StepMode="auto";
            app.sldLagTime.MinorTicksMode='auto';

            % app.sldLagTime.Step=log10(app.msd(1,1));
            drawnow

            % % clear track exclusion flag
            % app.blnTrackExcluded=false;

            % update bpgPlotData
            app.optMPMSD.Value=true;
            drawnow


            % plot MP-MSD by default
            reset(app.axePlot)
            app.axePlot.Box='on';
            loglog(app.axePlot,app.msd(:,1),app.msd(:,2:4),'LineWidth',1);

            app.axePlot.XLabel.String='lag time (s)';
            app.axePlot.YLabel.String='MSD (m^2\rm)';
            app.axePlot.XLim=10.^[floor(log10(min(app.msd(:,1)))),ceil(log10(max(app.msd(:,1))))];
            app.axePlot.YLim=10.^[floor(log10(min(app.msd(:,2:4),[],'all'))),ceil(log10(max(app.msd(:,2:4),[],'all')))];
            app.axePlot.XTick=10.^(floor(log10(min(app.msd(:,1)))):ceil(log10(max(app.msd(:,1)))));
            app.axePlot.YTick=10.^(floor(log10(min(app.msd(:,2)))):ceil(log10(max(app.msd(:,2)))));
            app.axePlot.XMinorTick='on';
            app.axePlot.YMinorTick='on';


            hold(app.axePlot,'on');
            x1=10.^(log10(max(app.msd(:,1))/min(app.msd(:,1)))*3/4)*min(app.msd(:,1));
            x2=max(app.msd(:,1));
            y1=interp1(app.msd(:,1),app.msd(:,4),x1);
            y2=y1*x2/x1;
            plot(app.axePlot,[x1,x2],[y1,y2],'k--','LineWidth',1);
            lg=legend(app.axePlot);
            lg.Box='off';
            lg.String=[{'\Delta\itx\rm^2'},{'\Delta\ity\rm^2'},{'\Delta\itr\rm^2'},{},{}];
            lg.Location='best';
            lg.Color='none';
            hold(app.axePlot,'off');
            app.optMPMSD.Value=true;


            drawnow


        end

        % Selection changed function: bgpPlotData
        function bgpPlotDataSelectionChanged(app, event)
            selectedButton = app.bgpPlotData.SelectedObject;
            switch selectedButton
                case app.optMPMSD
                    if ~isempty(app.msd)
                        cla(app.axePlot,'reset');

                        legend(app.axePlot,'off');
                        app.axePlot.Box='on';

                        loglog(app.axePlot,app.msd(:,1),app.msd(:,2:4),'LineWidth',1);

                        app.axePlot.XLabel.String='lag time (s)';
                        app.axePlot.YLabel.String='MSD (m^2\rm)';
                        app.axePlot.XLim=10.^[floor(log10(min(app.msd(:,1)))),ceil(log10(max(app.msd(:,1))))];
                        app.axePlot.YLim=10.^[floor(log10(min(app.msd(:,2:4),[],'all'))),ceil(log10(max(app.msd(:,2),[],'all')))];
                        app.axePlot.XTick=10.^(floor(log10(min(app.msd(:,1)))):ceil(log10(max(app.msd(:,1)))));
                        app.axePlot.YTick=10.^(floor(log10(min(app.msd(:,2)))):ceil(log10(max(app.msd(:,2)))));
                        app.axePlot.XMinorTick='on';
                        app.axePlot.YMinorTick='on';


                        hold(app.axePlot,'on');
                        x1=10.^(log10(max(app.msd(:,1))/min(app.msd(:,1)))*3/4)*min(app.msd(:,1));
                        x2=max(app.msd(:,1));
                        y1=interp1(app.msd(:,1),app.msd(:,4),x1);
                        y2=y1*x2/x1;
                        plot(app.axePlot,[x1,x2],[y1,y2],'k--','LineWidth',1);
                        lg=legend(app.axePlot);
                        lg.Box='off';
                        lg.Color='none';
                        lg.String=[{'\Delta\itx\rm^2'},{'\Delta\ity\rm^2'},{'\Delta\itr\rm^2'},{},{}];
                        lg.Location='best';
                        hold(app.axePlot,'off');

                        app.optMPMSD.Value=true;
                    end
                case app.optGSER
                    if ~isempty(app.msd)
                        cla(app.axePlot,'reset');

                        app.axePlot.Box='on';
                        if ~isempty(app.G)
                            loglog(app.axePlot,app.G(:,1),app.G(:,2:3),'LineWidth',1);


                            app.axePlot.XLabel.String='angular frequency (rad/s)';
                            app.axePlot.YLabel.String='Moduli (Pa)';
                            app.axePlot.XLim=10.^[floor(log10(min(app.G(:,1)))),ceil(log10(max(app.G(:,1))))];
                            app.axePlot.YLim=10.^[floor(log10(min([app.G(app.G(:,2)>0,2);app.G(app.G(:,3)>0,3)]))),...
                                ceil(log10(max([app.G(app.G(:,2)>0,2);app.G(app.G(:,3)>0,3)])))];
                            app.axePlot.XTick=10.^(floor(log10(min(app.G(:,1)))):ceil(log10(max(app.G(:,1)))));
                            app.axePlot.YTick=10.^(floor(log10(min([app.G(app.G(:,2)>0,2);app.G(app.G(:,3)>0,3)]))):...
                                ceil(log10(max([app.G(app.G(:,2)>0,2);app.G(app.G(:,3)>0,3)]))));
                            app.axePlot.XMinorTick='on';
                            app.axePlot.YMinorTick='on';

                            lg=legend(app.axePlot);

                            lg.Interpreter='latex';
                            lg.String=[{'$G\prime$'},{'$G\prime\prime$'}];
                            lg.Location='best';
                            lg.Box='off';
                            lg.Color='none';
                        end
                    end
                case app.optAlpha_2
                    if ~isempty(app.msd)
                        cla(app.axePlot,'reset');

                        app.axePlot.Box='on';


                        line_color=app.axePlot.ColorOrder(1,:);
                        bar_color=rgb2hsv(line_color);

                        bar_color(3)=bar_color(3)+(1-bar_color(3))*3/4;
                        bar_color=hsv2rgb(bar_color);


                        errorbar(app.axePlot,app.alpha_2_i(:,1),app.alpha_2_i(:,2),app.alpha_2_i(:,3),...
                            'LineStyle','none','CapSize',0,'LineWidth',1,'Color',bar_color,'HandleVisibility','off');
                        hold(app.axePlot,'on');
                        plot(app.axePlot,app.alpha_2_i(:,1),app.alpha_2_i(:,2),'LineWidth',1,'Color',line_color);

                        line_color=app.axePlot.ColorOrder(2,:);
                        bar_color=rgb2hsv(line_color);

                        bar_color(3)=bar_color(3)+(1-bar_color(3))*3/4;
                        bar_color=hsv2rgb(bar_color);

                        errorbar(app.axePlot,app.alpha_2(:,1),app.alpha_2(:,2),app.alpha_2(:,3),...
                            'LineStyle','none','CapSize',0,'LineWidth',1,'Color',bar_color,'HandleVisibility','off');
                        plot(app.axePlot,app.alpha_2(:,1),app.alpha_2(:,2),'LineWidth',1,'Color',line_color);

                        hold(app.axePlot,'off');
                        app.axePlot.XScale='log';
                        app.axePlot.YScale='linear';


                        app.axePlot.XLabel.String='lag time (s)';
                        app.axePlot.YLabel.Interpreter='tex';
                        app.axePlot.YLabel.String='\itα\rm_2 (-)';
                        app.axePlot.XLim=10.^[floor(log10(min(app.msd(:,1)))),ceil(log10(max(app.msd(:,1))))];
                        %                     app.axePlot.YLim=[floor(min(0,min(app.alpha_2(:,2)))),ceil(max(app.alpha_2(:,2)))];
                        app.axePlot.XTick=10.^(floor(log10(min(app.msd(:,1)))):ceil(log10(max(app.msd(:,1)))));
                        %                     app.axePlot.YTick=10.^(floor(log10(min(app.msd(:,2)))):ceil(log10(max(app.msd(:,2)))));
                        app.axePlot.XMinorTick='on';
                        app.axePlot.YMinorTick='on';


                        lg=legend(app.axePlot);
                        lg.String=[{'single'},{'multiple'}];
                        lg.Location='best';
                        lg.Color='none';
                        lg.Box='off';
                    end
                case app.optVanHove

                    if ~isempty(app.msd)
                        % dt=app.msd(app.sldLagTime.Value,1);
                        % [~,ind]=min(abs(app.msd(:,1)-dt));
                        ind=app.sldLagTime.Value;

                        cla(app.axePlot,'reset');
                        app.axePlot.Box='on';

                        legend(app.axePlot,'off');
                        N=app.van_Hove{ind,1};
                        edges=app.van_Hove{ind,2};
                        bins=edges(1:end-1)+diff(edges)./2;

                        plot(app.axePlot,bins,N,'.','LineWidth',1);
                        app.axePlot.XScale='linear';
                        app.axePlot.YScale='log';
                        hold(app.axePlot,'on');
                        plot(app.axePlot,bins,exp(-bins.^2./(2.*app.msd(ind,2)))./sqrt(2.*pi.*app.msd(ind,2)),'-','LineWidth',1,'Color',[0.8500 0.3250 0.0980])
                        hold(app.axePlot,'off');


                        if ~isempty(app.van_Hove_rec)
                            [~,ind2]=min(abs(app.msd(:,1)-app.dtP));
                            if ind2==ind
                                hold(app.axePlot,'on');
                                plot(app.axePlot,app.van_Hove_rec(:,1),app.van_Hove_rec(:,2),'-','LineWidth',1,'Color',[0.9290 0.6940 0.1250])
                                hold(app.axePlot,'off');
                            end
                        end

                        app.axePlot.XLim=[-10.^max(log10(abs(bins))),10.^max(log10(abs(bins)))];
                        app.axePlot.YLim=10.^[floor(log10(min(N(N>0)))),ceil(log10(max(N)))];
                        app.axePlot.YTick=10.^(floor(log10(min(N(N>0)))):ceil(log10(max(N))));
                        app.axePlot.XMinorTick='on';
                        app.axePlot.YMinorTick='on';


                        app.axePlot.XLabel.Interpreter='tex';
                        app.axePlot.XLabel.String='Δ\itx\rm (m)';
                        app.axePlot.YLabel.Interpreter='tex';
                        app.axePlot.YLabel.String='prob. density (-)';

                        tx=text(app.axePlot,app.axePlot.XLim(1),app.axePlot.YLim(2),[' Δ\itt\rm = ' num2str(app.msd(ind,1),'%.3g') ' s']);
                        tx.VerticalAlignment='top';
                        tx.Interpreter='tex';
                    end

                case app.optChi4
                    if ~isempty(app.chi_cross)

                        cla(app.axePlot,'reset');
                        app.axePlot.Box='on';

                        lag=app.chi_cross{1}.*app.edtShutterTime.Value;
                        d=app.chi_cross{2}./(app.edtParticleRadius.Value.*1e-6);
                        % chiS_Z=app.chi4_s{3};
                        % chiC_Z=app.chi4_c{3};
                        % chiT_Z=app.chi4_t{3};
                        % F_Z=app.F{3};

                        chi_cross_mat=app.chi_cross{3};

                        % legend(app.axePlot,'off');
                        % hold(app.axePlot,"on")
                        % % semilogx(app.axePlot,app.F(:,1),app.F(:,2),'-','LineWidth',1);
                        %
                        % cmap=parula(length(d));
                        % arrayfun(@(i) semilogx(app.axePlot,lag.*app.edtShutterTime.Value,chiC_Z(:,i),'-',...
                        %     'LineWidth',1,'Color',cmap(i,:)),1:length(d),'UniformOutput',0);
                        %
                        %
                        % hold(app.axePlot,'off');
                        %
                        hold(app.axePlot,'on');
                        [X,Y]=meshgrid(lag(:),d(:));
                        Z=chi_cross_mat;
                        [~,hc]=contourf(app.axePlot,X,Y,Z);
                        app.axePlot.XScale='log';
                        app.axePlot.YScale='log';
                        hc.LineStyle='none';
                        hc.Fill='on';
                        cb=colorbar(app.axePlot,"north","AxisLocation","in");

                        l=plot(app.axePlot,app.msd(:,1),sqrt(app.msd(:,4)./2)./(app.edtParticleRadius.Value.*1e-6),'k--','LineWidth',1);

                        app.axePlot.XScale='log';
                        app.axePlot.YScale='log';
                        app.axePlot.XMinorTick='on';
                        app.axePlot.YMinorTick='on';
                        app.axePlot.XLabel.String='lag time (s)';
                        app.axePlot.YLabel.Interpreter='tex';
                        app.axePlot.YLabel.String='tolerance (\ita\rm)';
                        app.axePlot.XLimMode='auto';
                        app.axePlot.YLimMode='auto';
                        app.axePlot.YTick=10.^(ceil(log10(app.axePlot.YLim(1))):floor(log10(app.axePlot.YLim(2))));
                        app.axePlot.YMinorTick='on';

                        yyaxis(app.axePlot,"right")

                        msd_length=length(app.msd(:,1));
                        length_keep=floor(10^(log10(msd_length)/2));
                        % nlog=floor(10^(3*log10(msd_length)/4));

                        idx1=1:length_keep;
                        idx2=unique(round(logspace(log10(length_keep+1),log10(msd_length),length_keep)));
                        idx=unique([idx1,idx2]);

                        xPlot=app.msd(idx,1);
                        yPlot=app.chi_cross_ast(idx);
                        yerrPlot=app.chi_cross_var_ast(idx);


                        l2=errorbar(app.axePlot,xPlot,yPlot,yerrPlot,'-');
                        l2.LineWidth=1;
                        app.axePlot.YLimMode="auto";
                        % l2.Color='g';
                        app.axePlot.YLabel.String='\it\chi\rm*_{cross}';
                        app.axePlot.YLabel.Interpreter='tex';
                        app.axePlot.YLabel.Rotation=270;
                        hold(app.axePlot,'off');


                        % yyaxis(app.axePlot,'right');
                        % semilogx(app.axePlot,app.F(:,1),app.F(:,2),'-','LineWidth',1);
                        % app.axePlot.YScale='linear';
                        % app.axePlot.YLabel.String='F';
                        % app.axePlot.XLim=10.^[floor(log10(min(app.chi4_s(:,1)))),ceil(log10(max(app.chi4_s(:,1))))];
                        % app.axePlot.YLim=10.^[floor(log10(min(app.chi4_s(:,2)))),ceil(log10(max(app.chi4_s(:,2))))];
                        % app.axePlot.XTick=10.^(floor(log10(min(app.chi4_s(:,1)))):ceil(log10(max(app.chi4_s(:,1)))));
                        % app.axePlot.YTick=10.^(floor(log10(min(app.chi4_s(:,2)))):ceil(log10(max(app.chi4_s(:,2)))));
                        app.axePlot.XTick=10.^(ceil(log10(app.axePlot.XLim(1))):floor(log10(app.axePlot.XLim(2))));
                        app.axePlot.XMinorTick='on';
                    end


            end
        end

        % Value changed function: sldLagTime
        function sldLagTimeValueChanged(app, event)
            value = round(app.sldLagTime.Value);
            lim=app.sldLagTime.Limits;
            if value<lim(1)
                value=lim(1);
            end
            if value>lim(2)
                value=lim(2);
            end
            app.sldLagTime.Value=value;

            if ~isempty(app.msd)
                % slider_value=10.^value;
                % [~,ind]=min(abs(app.msd(:,1)-slider_value));
                % app.sldLagTime.Value=log10(app.msd(ind,1));

                drawnow

                if app.optVanHove.Value


                    % dt=10.^value;
                    dt=app.msd(value,1);
                    ind=value;


                    % [~,ind]=min(abs(app.msd(:,1)-dt));

                    reset(app.axePlot)
                    app.axePlot.Box='on';
                    app.axePlot.XMinorTick='on';
                    app.axePlot.YMinorTick='on';

                    legend(app.axePlot,'off');
                    N=app.van_Hove{ind,1};
                    edges=app.van_Hove{ind,2};
                    bins=edges(1:end-1)+diff(edges)./2;

                    plot(app.axePlot,bins,N,'.','LineWidth',1);
                    app.axePlot.XScale='linear';
                    app.axePlot.YScale='log';
                    hold(app.axePlot,'on')
                    plot(app.axePlot,bins,exp(-bins.^2./(2.*app.msd(ind,2)))./sqrt(2.*pi.*app.msd(ind,2)),'-','LineWidth',1,'Color',[0.8500 0.3250 0.0980])
                    hold(app.axePlot,'off')


                    if ~isempty(app.van_Hove_rec)
                        [~,ind2]=min(abs(app.msd(:,1)-app.dtP));
                        if ind2==ind
                            hold(app.axePlot,'on');
                            plot(app.axePlot,app.van_Hove_rec(:,1),app.van_Hove_rec(:,2),'-','LineWidth',1,'Color',[0.9290 0.6940 0.1250])
                            hold(app.axePlot,'off');
                        end
                    end


                    app.axePlot.XLim=[-10.^max(log10(abs(bins))),10.^max(log10(abs(bins)))];
                    app.axePlot.YLim=10.^[floor(log10(min(N(N>0)))),ceil(log10(max(N)))];
                    app.axePlot.YTick=10.^(floor(log10(min(N(N>0)))):ceil(log10(max(N))));
                    app.axePlot.Title.String='van Hove';
                    app.axePlot.XLabel.Interpreter='tex';
                    app.axePlot.XLabel.String='\Delta\itx\rm (m)';
                    app.axePlot.YLabel.Interpreter='tex';
                    app.axePlot.YLabel.String='prob. density (-)';


                    tx=text(app.axePlot,app.axePlot.XLim(1),app.axePlot.YLim(2),[' Δ\itt\rm = ' num2str(app.msd(ind,1),'%.3g') ' s']);
                    tx.VerticalAlignment='top';
                end

            end
        end

        % Button pushed function: btnRemove
        function btnRemoveButtonPushed(app, event)
            t=app.tblCurrentTracks.Data;
            if isempty(app.CurrentSelectedRow)||app.CurrentSelectedRow>length(t(:,1))

                uialert(app.UIFigure,'Selet a row first.','No row selected.','Icon','warning')
                return
            end


            t(app.CurrentSelectedRow,:)=[];


            app.tblCurrentTracks.Data=t;
            % app.blnTrackExcluded=false;

            % update globel tracks data

            tt=app.tblCurrentTracks.Data;

            if ~isempty(tt)

                [N,~]=size(tt); % N is the number of track files
                tr_list=cell(N,1); % pre-allocate
                lastNTrack=0;
                for l=1:N
                    tr=load([tt{l,1} tt{l,2}],'tr');
                    tr=tr.tr;

                    % regard multiple track file as one with extra tracks.
                    % effectively adding to the tr_id's.
                    tr(:,4)=tr(:,4)+lastNTrack;
                    tr=sortrows(tr,[4,3]);

                    % separate by tracks into cell array |tr_list|
                    ind=[[1;find(diff(tr(:,4))~=0)+1],[find(diff(tr(:,4))~=0);length(tr(:,4))]];
                    tr_temp=cell(length(ind(:,1)),1);
                    for i=1:length(ind(:,1))
                        tr_temp{i}=tr(ind(i,1):ind(i,2),:);
                    end % i

                    % if length(tr_temp)>1
                    %     tr_temp=drift_canceling(app,tr_temp);
                    % end

                    tr_list{l}=tr_temp;
                    lastNTrack=lastNTrack+length(tr_temp);
                end
                % app.tracks=tr_list;
                % app.new_tracks(app,tr_list);
                new_tracks(app,tr_list);
            end


            app.msd=[]; % this is the condition that the tracks have not been calculate for the first time

            app.excludedTrackID=[]; % clear excludedTrackID
        end

        % Button pushed function: btnLucy
        function btnLucyButtonPushed(app, event)
            if isempty(app.msd)
                uialert(app.UIFigure,'Calculate first.','Blank data','Icon','info')
                return;
            end
            dt=app.msd(app.sldLagTime.Value,1);
            % dt=10.^app.sldLagTime.Value;


            app.btnLucy.Enable='off';
            app.Lucy_dialogBox=Lucy_dlg(app,app.msd,app.van_Hove,dt);


        end

        % Button pushed function: btnSaveButton
        function btnSaveButtonPushed(app, event)
            if isempty(app.msd)&isempty(app.chi_cross)
                uialert(app.UIFigure,'No results saved.','Canceled','Icon','info');
                figure(app.UIFigure);
                return
            else
                path=uigetdir(app.CurrentPath,'Select folder for saving data');
                if path~=0
                    app.CurrentPath=[path '\'];

                    if ~isempty(app.msd)
                        msd_i=app.msd_i;
                        msd=app.msd;
                        G=app.G;
                        alpha_2=app.alpha_2;
                        alpha_2_i=app.alpha_2_i;
                        van_Hove=app.van_Hove;

                        save([path '\msd_data.mat'], ...
                            'msd_i','msd','G','alpha_2',...
                            'alpha_2','alpha_2_i','van_Hove');
                    end

                    if~isempty(app.P_D)
                        p_D=app.p_D;
                        P_D=app.P_D;
                        van_Hove_rec=app.van_Hove_rec;
                        save([path '\PD_data_' sprintf('%.2e',app.dtP),'.mat'],...
                            'p_D','P_D','van_Hove_rec');
                    end

                    if ~isempty(app.chi_cross)
                        chi_cross_save=app.chi_cross;
                        chi_cross_var_save=app.chi_cross_var;
                        chi_cross_ast=[app.msd(:,1),app.chi_cross_ast,app.chi_cross_var_ast];


                        save(fullfile(path,'chi4.mat'),"chi_cross_save","chi_cross_var_save",...
                            "chi_cross_ast");
                    end
                else
                    uialert(app.UIFigure,'No results saved.','Canceled','Icon','info');
                    figure(app.UIFigure);
                    return
                end


                % if isempty(app.msd)
                %     uialert(app.UIFigure,'No MSD data to save.','Blank data','Icon','info');
                %     return
                % end
                % path=uigetdir(app.CurrentPath,'Select folder for saving data');
                % if path~=0
                %
                %
                %     msd_i=app.msd_i;
                %     msd=app.msd;
                %     G=app.G;
                %     alpha_2=app.alpha_2;
                %     alpha_2_i=app.alpha_2_i;
                %     van_Hove=app.van_Hove;
                %
                %     save([path '\msd_data.mat'], ...
                %         'msd_i','msd','G','alpha_2',...
                %         'alpha_2','alpha_2_i','van_Hove');
                %
                %     app.CurrentPath=[path '\'];
                %     if isempty(app.P_D)
                %         uialert(app.UIFigure,'No P(D) data to save.','Blank data','Icon','info');
                %         return
                %     end
                %
                %     p_D=app.p_D;
                %     P_D=app.P_D;
                %     van_Hove_rec=app.van_Hove_rec;
                %     save([path '\PD_data_' sprintf('%.2e',app.dtP),'.mat'],...
                %         'p_D','P_D','van_Hove_rec');
                %     app.CurrentPath=[path '\'];
                % else
                %     uialert(app.UIFigure,'No results saved.','Canceled','Icon','info');
                %     figure(app.UIFigure);
                %     return
                % end
            end
        end

        % Button pushed function: btnGSEROpt
        function btnGSEROptButtonPushed(app, event)
            if isempty(app.msd)
                uialert(app.UIFigure,'Calculate first.','Blank data','Icon','info')
                return;
            end

            app.btnGSEROpt.Enable='off';
            T=app.edtTemperature.Value;
            a=app.edtParticleRadius.Value*1e-6;

            app.GSER_dialogBox=GSER_dlg(app,app.msd,T,a);

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            delete(app.GSER_dialogBox)
            delete(app.traj_dialogBox)
            delete(app.Lucy_dialogBox)

            delete(app)
        end

        % Button pushed function: btnChi_4
        function btnChi_4ButtonPushed(app, event)
            if isempty(app.msd)
                uialert(app.UIFigure,"No calculated data. Calculate first.","Calculate first",...
                    "Icon","error","Modal");
                return;
            end

            selection=uiconfirm(app.UIFigure,"Be sure to press Calculate first. Continue?","Confirm proceeding",...
                "Icon","question",...
                "Options",{'Yes','No'});
            if strcmp(selection,'No')
                return;
            end


            % clear old data
            % app.chi4_s=[];
            % app.chi4_c=[];
            % app.chi4_t=[];
            % app.F=[];


            % update status
            app.lblStatus.Interpreter='tex';
            app.lblStatus.Text='\it\chi\rm_4...';

            a=app.edtParticleRadius.Value.*1e-6;
            res=app.edtResolution.Value.*1e-6;

            % d=a./res; % overlap tolerance in pixel;
            tt=app.tblCurrentTracks.Data;
            if isempty(tt)
                uialert(app.UIFigure,'No tracks added.','Blank data','Modal',true,'Icon','error')
                return
            else
                tr_list=app.tracks;
            end

            drawnow
            % [N_tr,~]=size(tt); % N is the number of track files
            % tr_list=cell(N_tr,1);
            % for k=1:N_tr
            %     %% load the k-th track data
            %     tr=load([tt{k,1} tt{k,2}],'tr');
            %     tr=tr.tr;
            %
            %     tr_list{k}=sortrows(tr,[4,3]);
            %
            % end


            % if app.blnTrackExcluded
            %     N=length(app.tracks); % N is the number of track files
            %     for l=1:N
            %         tr_temp=app.tracks{l};
            %         excluded_rows=false(length(tr_temp),1);
            %         for j=1:length(tr_temp)
            %             t=tr_temp{j};
            %             if ismember(t(1,4),app.excludedTrackID)
            %                 excluded_rows(j)=true;
            %             end
            %         end
            %         tr_temp(excluded_rows)=[];
            %         app.tracks{l}=tr_temp;
            %
            %
            %     end
            %     tr_list=app.tracks;
            %


            if ~isempty(app.excludedTrackID)
                N=length(app.tracks); % N is the number of track files
                tr_list=cell(N,1);
                for l=1:N
                    tr_temp=app.tracks{l};
                    excluded_rows=false(length(tr_temp),1);
                    for j=1:length(tr_temp)
                        t=tr_temp{j};
                        if ismember(t(1,4),app.excludedTrackID)
                            excluded_rows(j)=true;
                        end
                    end
                    tr_temp(excluded_rows)=[];
                    tr_list{l}=tr_temp;

                    % tr=cell2mat(tr_temp);
                    % tr_list{l}=sortrows(tr,[4,3]);


                end
            end


            %% Determine the range of tolerance by MSD
            d_min=10^(ceil(log10(res))-2); % minimum is the static error of positioning

            d_max=10^(ceil(log10(sqrt(max(app.msd(:,2)))))+1);


            decades=log10(d_max)-log10(d_min);
            d=logspace(log10(d_min),log10(d_max),5*decades+1);


            % [lag,chiC_Z,chiS_Z,F_Z]=...
            %     compute_chi_cross_vs_lag_par(app,tr_list,d./res);
            % chiT_Z=chiC_Z+chiS_Z;


            % N_tol=length(d);
            %
            % for m=1:N_tol
            %
            %
            %     F=cell(N_tr,1);
            %     chiS=cell(N_tr,1);
            %     chiC=cell(N_tr,1);
            %
            %     for k=1:N_tr
            %         %% load the k-th track data
            %         tr=load([tt{k,1} tt{k,2}],'tr');
            %         tr=tr.tr;
            %
            %         %% How many different values of frame lags can the data afford?
            %         N_fr=max(tr(:,3))-min(tr(:,3))+1;
            %
            %
            %         % group positions of each particle into a cell array by its tr_id
            %         tr=sortrows(tr,[4,3]); % sort by tr_id first
            %
            %         ind=[[1;find(diff(tr(:,4))~=0)+1],[find(diff(tr(:,4))~=0);length(tr(:,4))]];
            %         tr_temp=arrayfun(@(i) tr(ind(i,1):ind(i,2),:),(1:length(ind(:,1)))','UniformOutput',0);
            %
            %
            %     end
            %
            %
            %
            %
            %
            %
            % end
            %


            [lags,chi_raw_mat,chi_unb_mat,chi_sing_mat,Q_mat,var_raw_mat,var_unb_mat,bias_mat] = ...
                compute_chi_cross_vs_lag_par(app,tr_list, d./res);


            app.chi_cross={lags(:),d(:)',chi_unb_mat};
            app.chi_cross_var={lags(:),d(:)',var_unb_mat};
            % chi_cross_raw={lags(:),d(:)',chi_raw_mat};
            % chi_cross_var_raw={lags(:),d(:)',var_raw_mat};
            % bias={lags(:),delta(:)',bias_mat};

            % chi_sing={lags(:),d(:)',chi_sing_mat};

            F = griddedInterpolant({d, lags}, chi_unb_mat, 'linear', 'none');
            y=sqrt(app.msd(:,4)./2);y=y(:);
            x=lags(2:end);x=x(:);
            app.chi_cross_ast = F(y,x);

            F=griddedInterpolant({d,lags},var_unb_mat,'linear','none');
            y=sqrt(app.msd(:,4)./2);y=y(:);
            x=lags(2:end);x=x(:);

            app.chi_cross_var_ast=F(y,x);


            %% commit results and update plot


            % app.chi4_s={lag(:),d(:)',chiS_Z};
            % app.chi4_c={lag(:),d(:)',chiC_Z};
            % app.chi4_t={lag(:),d(:)',chiT_Z};
            % app.F={lag(:),d(:)',F_Z};
            app.lblStatus.Text='Done!';
            app.lblStatus.Interpreter='none';

            % update plot
            if app.optChi4.Value

                cla(app.axePlot,'reset');
                app.axePlot.Box='on';

                lag=app.chi_cross{1}.*app.edtShutterTime.Value;
                d=app.chi_cross{2}./(app.edtParticleRadius.Value.*1e-6);
                % chiS_Z=app.chi4_s{3};
                % chiC_Z=app.chi4_c{3};
                % chiT_Z=app.chi4_t{3};
                % F_Z=app.F{3};

                chi_cross_mat=app.chi_cross{3};
                % chi_cross_var_mat=app.chi_cross_var{3};

                % legend(app.axePlot,'off');
                % hold(app.axePlot,"on")
                % % semilogx(app.axePlot,app.F(:,1),app.F(:,2),'-','LineWidth',1);
                %
                % cmap=parula(length(d));
                % arrayfun(@(i) semilogx(app.axePlot,lag.*app.edtShutterTime.Value,chiC_Z(:,i),'-',...
                %     'LineWidth',1,'Color',cmap(i,:)),1:length(d),'UniformOutput',0);
                %
                %
                % hold(app.axePlot,'off');
                %
                hold(app.axePlot,'on');
                [X,Y]=meshgrid(lag(:),d(:));
                Z=chi_cross_mat;
                [~,hc]=contourf(app.axePlot,X,Y,Z);
                app.axePlot.XScale='log';
                app.axePlot.YScale='log';
                hc.LineStyle='none';
                hc.Fill='on';
                cb=colorbar(app.axePlot,"north","AxisLocation","in");


                l=plot(app.axePlot,app.msd(:,1),sqrt(app.msd(:,4)./2)./(app.edtParticleRadius.Value.*1e-6),'k--','LineWidth',1);

                app.axePlot.XScale='log';
                app.axePlot.YScale='log';
                app.axePlot.XMinorTick='on';
                app.axePlot.YMinorTick='on';
                app.axePlot.XLabel.String='lag time (s)';
                app.axePlot.YLabel.Interpreter='tex';
                app.axePlot.YLabel.String='tolerance (\ita\rm)';
                app.axePlot.XLimMode='auto';
                app.axePlot.YLimMode='auto';
                app.axePlot.YTick=10.^(ceil(log10(app.axePlot.YLim(1))):floor(log10(app.axePlot.YLim(2))));
                app.axePlot.YMinorTick='on';

                yyaxis(app.axePlot,"right")

                msd_length=length(app.msd(:,1));
                length_keep=floor(10^(log10(msd_length)/2));
                % nlog=floor(10^(3*log10(msd_length)/4));

                idx1=1:length_keep;
                idx2=unique(round(logspace(log10(length_keep+1),log10(msd_length),length_keep)));
                idx=unique([idx1,idx2]);

                xPlot=app.msd(idx,1);
                yPlot=app.chi_cross_ast(idx);
                yerrPlot=app.chi_cross_var_ast(idx);


                l2=errorbar(app.axePlot,xPlot,yPlot,yerrPlot,'-');
                l2.LineWidth=1;
                app.axePlot.YLimMode="auto";
                % l2.Color='g';
                app.axePlot.YLabel.String='\it\chi\rm*_{cross}';
                app.axePlot.YLabel.Interpreter='tex';
                app.axePlot.YLabel.Rotation=270;
                hold(app.axePlot,'off');


                % yyaxis(app.axePlot,'right');
                % semilogx(app.axePlot,app.F(:,1),app.F(:,2),'-','LineWidth',1);
                % app.axePlot.YScale='linear';
                % app.axePlot.YLabel.String='F';
                % app.axePlot.XLim=10.^[floor(log10(min(app.chi4_s(:,1)))),ceil(log10(max(app.chi4_s(:,1))))];
                % app.axePlot.YLim=10.^[floor(log10(min(app.chi4_s(:,2)))),ceil(log10(max(app.chi4_s(:,2))))];
                % app.axePlot.XTick=10.^(floor(log10(min(app.chi4_s(:,1)))):ceil(log10(max(app.chi4_s(:,1)))));
                % app.axePlot.YTick=10.^(floor(log10(min(app.chi4_s(:,2)))):ceil(log10(max(app.chi4_s(:,2)))));
                app.axePlot.XTick=10.^(ceil(log10(app.axePlot.XLim(1))):floor(log10(app.axePlot.XLim(2))));
                app.axePlot.XMinorTick='on';


            end

        end

        % Button pushed function: btnTrajectories
        function btnTrajectoriesPushed(app, event)
            if isempty(app.msd)
                uialert(app.UIFigure,'Calculate first.','Blank data','Icon','info')
                return;
            end

            %% input all loaded tracks to the diaglog box every time
            % tt=app.tblCurrentTracks.Data;
            % [N,~]=size(tt); % N is the number of track files
            % lastNTrack=0;
            %
            % for l=1:N
            %     tr=load([tt{l,1} tt{l,2}],'tr');
            %     tr=tr.tr;
            %     % regard multiple track file as one with extra tracks.
            %     % effectively adding to the tr_id's.
            %     tr(:,4)=tr(:,4)+lastNTrack;
            %     tr=sortrows(tr,[4,3]);
            %
            %     % separate by tracks into cell array |tr_list|
            %     ind=[[1;find(diff(tr(:,4))~=0)+1],[find(diff(tr(:,4))~=0);length(tr(:,4))]];
            %     tr_temp=cell(length(ind(:,1)),1);
            %     for i=1:length(ind(:,1))
            %         tr_temp{i}=tr(ind(i,1):ind(i,2),:);
            %     end % i
            %
            %     if length(tr_temp)>1
            %         tr_temp=drift_canceling(app,tr_temp);
            %     end
            %
            %     tr_list{l}=tr_temp;
            %     lastNTrack=lastNTrack+length(tr_temp);
            % end
            % app.tracks=tr_list;

            %% open dialog
            app.traj_dialogBox=traj_dlg(app,app.tracks,app.msd_i_init,app.msd_init);

        end

        % Selection changed function: tblCurrentTracks
        function tblCurrentTracksSelectionChanged(app, event)
            selection = app.tblCurrentTracks.Selection;
            app.CurrentSelectedRow=selection;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Track Analysis // Weixiang Sun // mswxsun@scut.edu.cn';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.axePlot = uiaxes(app.UIFigure);
            xlabel(app.axePlot, 'X')
            ylabel(app.axePlot, 'Y')
            app.pnlParameters = uipanel(app.UIFigure);
            app.pnlParameters.Title = 'Parameters';
            app.pnlParameters.Tag = 'pnlParameters';
            app.TemperatureCLabel = uilabel(app.pnlParameters);
            app.TemperatureCLabel.Text = 'Temperature (°C):';
            app.edtTemperature = uieditfield(app.pnlParameters, 'numeric');
            app.edtTemperature.Limits = [0 Inf];
            app.edtTemperature.ValueChangedFcn = createCallbackFcn(app, @edtTemperatureValueChanged, true);
            app.edtTemperature.Value = 30;
            app.ParticleradiusmLabel = uilabel(app.pnlParameters);
            app.ParticleradiusmLabel.Text = 'Particle radius (μm):';
            app.edtParticleRadius = uieditfield(app.pnlParameters, 'numeric');
            app.edtParticleRadius.Limits = [0 Inf];
            app.edtParticleRadius.ValueChangedFcn = createCallbackFcn(app, @edtParticleRadiusValueChanged, true);
            app.edtParticleRadius.Value = 0.1;
            app.ShuttertimesLabel = uilabel(app.pnlParameters);
            app.ShuttertimesLabel.Text = 'Shutter time (s):';
            app.edtShutterTime = uieditfield(app.pnlParameters, 'numeric');
            app.edtShutterTime.Limits = [0 Inf];
            app.edtShutterTime.ValueChangedFcn = createCallbackFcn(app, @edtShutterTimeValueChanged, true);
            app.edtShutterTime.Value = 0.020142;
            app.ResolutionmpixelLabel = uilabel(app.pnlParameters);
            app.ResolutionmpixelLabel.Text = 'Resolution (μm/pixel):';
            app.edtResolution = uieditfield(app.pnlParameters, 'numeric');
            app.edtResolution.Limits = [0 Inf];
            app.edtResolution.ValueChangedFcn = createCallbackFcn(app, @edtResolutionValueChanged, true);
            app.edtResolution.Value = 0.1083266701;
            app.btnAddTrack = uibutton(app.UIFigure, 'push');
            app.btnAddTrack.ButtonPushedFcn = createCallbackFcn(app, @btnAddTrackButtonPushed, true);
            app.btnAddTrack.Text = 'Add...';
            app.tblCurrentTracks = uitable(app.UIFigure);
            app.tblCurrentTracks.ColumnName = '';
            app.tblCurrentTracks.SelectionType = 'row';
            app.tblCurrentTracks.SelectionChangedFcn = createCallbackFcn(app, @tblCurrentTracksSelectionChanged, true);
            app.tblCurrentTracks.Multiselect = 'off';
            app.btnCalculate = uibutton(app.UIFigure, 'push');
            app.btnCalculate.ButtonPushedFcn = createCallbackFcn(app, @btnCalculateButtonPushed, true);
            app.btnCalculate.Text = 'Calculate';
            app.bgpPlotData = uibuttongroup(app.UIFigure);
            app.bgpPlotData.SelectionChangedFcn = createCallbackFcn(app, @bgpPlotDataSelectionChanged, true);
            app.bgpPlotData.Title = 'Plot data';
            app.optMPMSD = uiradiobutton(app.bgpPlotData);
            app.optMPMSD.Text = 'MSD';
            app.optMPMSD.Interpreter = 'html';
            app.optMPMSD.Value = true;
            app.optGSER = uiradiobutton(app.bgpPlotData);
            app.optGSER.Text = 'Modulus';
            app.optGSER.Interpreter = 'html';
            app.optAlpha_2 = uiradiobutton(app.bgpPlotData);
            app.optAlpha_2.Text = '<i>&#945;</i><sub>2</sub>';
            app.optAlpha_2.Interpreter = 'html';
            app.optVanHove = uiradiobutton(app.bgpPlotData);
            app.optVanHove.Text = 'van Hove';
            app.optVanHove.Interpreter = 'html';
            app.optChi4 = uiradiobutton(app.bgpPlotData);
            app.optChi4.Enable = 'off';
            app.optChi4.Text = '';
            app.optChi4.Interpreter = 'tex';
            app.btnChi_4 = uibutton(app.bgpPlotData, 'push');
            app.btnChi_4.ButtonPushedFcn = createCallbackFcn(app, @btnChi_4ButtonPushed, true);
            app.btnChi_4.Enable = 'off';
            app.btnChi_4.Interpreter = 'html';
            app.btnChi_4.Text = '<i>&#967</i><sub>cross</sub>';
            app.LagtimesLabel = uilabel(app.UIFigure);
            app.LagtimesLabel.Text = '# lags:';
            app.sldLagTime = uislider(app.UIFigure);
            app.sldLagTime.ValueChangedFcn = createCallbackFcn(app, @sldLagTimeValueChanged, true);
            app.btnLucy = uibutton(app.UIFigure, 'push');
            app.btnLucy.ButtonPushedFcn = createCallbackFcn(app, @btnLucyButtonPushed, true);
            app.btnLucy.Text = 'Lucy';
            app.btnRemove = uibutton(app.UIFigure, 'push');
            app.btnRemove.ButtonPushedFcn = createCallbackFcn(app, @btnRemoveButtonPushed, true);
            app.btnRemove.Text = 'Clear';
            app.btnSaveButton = uibutton(app.UIFigure, 'push');
            app.btnSaveButton.ButtonPushedFcn = createCallbackFcn(app, @btnSaveButtonPushed, true);
            app.btnSaveButton.Text = 'Save...';
            app.lblTrackFiles = uilabel(app.UIFigure);
            app.lblTrackFiles.Text = {'Track files'; '(*.mat):'};
            app.lblStatus = uilabel(app.UIFigure);
            app.lblStatus.Interpreter = 'tex';
            app.lblStatus.Text = '';
            app.btnGSEROpt = uibutton(app.UIFigure, 'push');
            app.btnGSEROpt.ButtonPushedFcn = createCallbackFcn(app, @btnGSEROptButtonPushed, true);
            app.btnGSEROpt.Text = 'GSER';
            app.btnTrajectories = uibutton(app.UIFigure, 'push');
            app.btnTrajectories.ButtonPushedFcn = createCallbackFcn(app, @btnTrajectoriesPushed, true);
            app.btnTrajectories.Text = 'Traj. excl.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = track_anal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end