classdef traj_dlg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrajectoriesUIFigure  matlab.ui.Figure
        Label                 matlab.ui.control.Label
        btnRecalculate        matlab.ui.control.Button
        lstExcluded           matlab.ui.control.ListBox
        ExcludeLabel          matlab.ui.control.Label
        lstTrajectories       matlab.ui.control.ListBox
        TracksLabel           matlab.ui.control.Label
        axeTrajectory         matlab.ui.control.UIAxes
        axeMSD_i              matlab.ui.control.UIAxes
    end


    properties (Access = private)
        tracks
        track_ids
        msd_i
        msd_i_ids
        msd% Description

        callingApp % Description
        plots % Description
        plot_ids
        txtTrackID
        plotMSD % Description
    end
    
    properties (Access = public)
    end

    methods (Access = private)

        function results = onLineClicked(app,event)
            clickedLine=event.Source;
            id_selected=str2double(clickedLine.DisplayName);

            app.lstTrajectories.Value=clickedLine.DisplayName;
            app.plots(app.plot_ids==id_selected).Color="#D95319";
            [app.plots(app.plot_ids~=id_selected).Color]=deal("#cccccc");
            app.axeMSD_i.Children=[app.plotMSD;...
                app.plots(app.plot_ids==id_selected);...
                app.plots(app.plot_ids~=id_selected)];
            traj_selected=app.tracks{app.track_ids==id_selected};

            cla(app.axeTrajectory,'reset');
            app.axeTrajectory.Box='on';

            legend(app.axeTrajectory,'off');

            plot(app.axeTrajectory,traj_selected(:,1),traj_selected(:,2),'o-');

            app.txtTrackID=text(app.axeTrajectory,...
                0.02,0.02,['Track #',num2str(id_selected)],...
                'Units','normalized');


            xlim=app.axeTrajectory.XLim;
            ylim=app.axeTrajectory.YLim;
            x_span=abs(diff(xlim));
            y_span=abs(diff(ylim));
            if x_span<y_span
                d=(y_span-x_span)/2;
                xlim(1)=xlim(1)-d;
                xlim(2)=xlim(2)+d;
                app.axeTrajectory.XLim=xlim;
            else
                d=(x_span-y_span)/2;
                ylim(1)=ylim(1)-d;
                ylim(2)=ylim(2)+d;
                app.axeTrajectory.YLim=ylim;

            end

            app.axeTrajectory.XTick=ceil(xlim(1)):floor(xlim(2));
            app.axeTrajectory.YTick=ceil(ylim(1)):floor(ylim(2));
        end
    end
    
    methods (Access = public)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, tracks, msd_i, msd)
            app.callingApp=caller;

            
            app.tracks=vertcat(tracks{:});
            app.msd_i=msd_i;
            app.msd=msd;


            %% initialize
            % construct indices for msd_i and tracks
            app.track_ids=zeros(length(app.tracks),1);
            for i=1:length(app.tracks)
                app.track_ids(i)=app.tracks{i}(1,4);
            end
            app.msd_i_ids=zeros(length(app.msd_i),1);
            for i=1:length(app.msd_i)
                app.msd_i_ids(i)=app.msd_i{i}(1,3);
            end

            % initialize listbox items
            listItems = arrayfun(@(x) num2str(x), app.track_ids, 'UniformOutput', false);
            app.lstTrajectories.Items=listItems;
            app.lstTrajectories.ValueIndex=1;


            cla(app.axeMSD_i,'reset');
            app.axeMSD_i.Box='on';

            legend(app.axeMSD_i,'off');

            % co=app.axeMSD_i.ColorOrder;
            % for i=1:length(co(:,1))
            %     co_2=rgb2hsv(co(i,:));
            %     co_2(3)=co_2(3)+(1-co_2(3))*3/4;
            %     co(i,:)=hsv2rgb(co_2);
            % end
            % app.axeMSD_i.ColorOrder=co;

            % add plots to axes control

            hold(app.axeMSD_i,'on');
            for i=1:length(app.msd_i)
                plot(app.axeMSD_i,app.msd_i{i}(:,1),app.msd_i{i}(:,2),...
                    'Color',"#cccccc",'LineWidth',1,'DisplayName',num2str(app.msd_i{i}(1,3)));

            end

            % establish plot id and let all plot selectable
            app.axeMSD_i.HitTest='on';
            app.axeMSD_i.PickableParts='all';
            app.plots=app.axeMSD_i.Children;
            app.plot_ids=zeros(length(app.plots),1);
            for i=1:length(app.plots)

                app.plot_ids(i)=str2double(app.plots(i).DisplayName);
                app.plots(i).HitTest='on';
                app.plots(i).PickableParts='all';
                app.plots(i).ButtonDownFcn=@(~,event) onLineClicked(app,event);

            end


            app.plotMSD=loglog(app.axeMSD_i,app.msd(:,1),app.msd(:,2),'k--','LineWidth',1);

            hold(app.axeMSD_i,'off');


            % cellfun(@(x) plot(app.axeMSD_i,x(:,1),x(:,2),'Color',"#cccccc",'LineWidth',1),app.msd_i,'UniformOutput',0);


            % Single out selected msd_i
            id_selected=str2double(app.lstTrajectories.Value);
            app.plots(app.plot_ids==id_selected).Color="#D95319";
            [app.plots(app.plot_ids~=id_selected).Color]=deal("#cccccc");
            app.axeMSD_i.Children=[app.plotMSD;...
                app.plots(app.plot_ids==id_selected);...
                app.plots(app.plot_ids~=id_selected)];


            % ind_rest=true(length(app.msd_i),1);
            % ind_rest(id_selected)=false;
            % msd_i_rest=app.msd_i(ind_rest);
            %
            % hold(app.axeMSD_i,'on')
            % cellfun(@(x) plot(app.axeMSD_i,x(:,1),x(:,2),'Color',"#cccccc",'LineWidth',1),msd_i_rest,'UniformOutput',0);
            % plot(app.axeMSD_i,app.msd_i{app.msd_i_ids==id_selected}(:,1),app.msd_i{app.msd_i_ids==id_selected}(:,2),'Color',	"#D95319",'LineWidth',1);
            %
            % app.axeMSD_i.XScale='log';
            % app.axeMSD_i.YScale='log';
            %
            % loglog(app.axeMSD_i,app.msd(:,1),app.msd(:,2),'k--','LineWidth',1)
            % hold(app.axeMSD_i,'off');

            app.axeMSD_i.XScale='log';
            app.axeMSD_i.YScale='log';

            app.axeMSD_i.XLabel.String='lag time (s)';
            app.axeMSD_i.YLabel.String='MSD (m^2\rm)';
            app.axeMSD_i.XLim=10.^[floor(log10(min(app.msd(:,1)))),ceil(log10(max(app.msd(:,1))))];
            app.axeMSD_i.YLim=10.^[floor(log10(min(app.msd(:,2:4),[],'all'))),ceil(log10(max(app.msd(:,2),[],'all')))];
            app.axeMSD_i.XTick=10.^(floor(log10(min(app.msd(:,1)))):ceil(log10(max(app.msd(:,1)))));
            app.axeMSD_i.YTick=10.^(floor(log10(min(app.msd(:,2)))):ceil(log10(max(app.msd(:,2)))));
            app.axeMSD_i.XMinorTick='on';
            app.axeMSD_i.YMinorTick='on';
            % plots=app.axeMSD_i.Children;

            % single out the selected track

            traj_selected=app.tracks{app.track_ids==id_selected};

            cla(app.axeTrajectory,'reset');
            app.axeTrajectory.Box='on';

            legend(app.axeTrajectory,'off');

            plot(app.axeTrajectory,traj_selected(:,1),traj_selected(:,2),'o-');

            app.txtTrackID=text(app.axeTrajectory,...
                0.02,0.02,['Track #',num2str(id_selected)],...
                'Units','normalized');

            xlim=app.axeTrajectory.XLim;
            ylim=app.axeTrajectory.YLim;
            x_span=abs(diff(xlim));
            y_span=abs(diff(ylim));
            if x_span<y_span
                d=(y_span-x_span)/2;
                xlim(1)=xlim(1)-d;
                xlim(2)=xlim(2)+d;
                app.axeTrajectory.XLim=xlim;
            else
                d=(x_span-y_span)/2;
                ylim(1)=ylim(1)-d;
                ylim(2)=ylim(2)+d;
                app.axeTrajectory.YLim=ylim;

            end

            app.axeTrajectory.XTick=ceil(xlim(1)):floor(xlim(2));
            app.axeTrajectory.YTick=ceil(ylim(1)):floor(ylim(2));


        end

        % Value changed function: lstTrajectories
        function lstTrajectoriesValueChanged(app, event)
            id_selected = str2double(app.lstTrajectories.Value);
            % cla(app.axeMSD_i,'reset');
            % app.axeMSD_i.Box='on';

            % legend(app.axeMSD_i,'off');

            % co=app.axeMSD_i.ColorOrder;
            % for i=1:length(co(:,1))
            %     co_2=rgb2hsv(co(i,:));
            %     co_2(3)=co_2(3)+(1-co_2(3))*3/4;
            %     co(i,:)=hsv2rgb(co_2);
            % end
            % app.axeMSD_i.ColorOrder=co;

            % Single out selected msd_i
            % id_selected=str2double(cell2mat(app.lstTrajectories.Value));


            % id_selected=str2double(cell2mat(app.lstTrajectories.Value));
            app.plots(app.plot_ids==id_selected).Color="#D95319";
            [app.plots(app.plot_ids~=id_selected).Color]=deal("#cccccc");
            app.axeMSD_i.Children=[app.plotMSD;...
                app.plots(app.plot_ids==id_selected);...
                app.plots(app.plot_ids~=id_selected)];


            % ind_rest=true(length(app.msd_i),1);
            % ind_rest(id_selected)=false;
            % msd_i_rest=app.msd_i(ind_rest);
            %
            % hold(app.axeMSD_i,'on')
            % cellfun(@(x) plot(app.axeMSD_i,x(:,1),x(:,2),'Color',"#cccccc",'LineWidth',1),msd_i_rest,'UniformOutput',0);
            % plot(app.axeMSD_i,app.msd_i{app.msd_i_ids==id_selected}(:,1),app.msd_i{app.msd_i_ids==id_selected}(:,2),'Color',	"#D95319",'LineWidth',1);
            %
            % app.axeMSD_i.XScale='log';
            % app.axeMSD_i.YScale='log';
            %
            % loglog(app.axeMSD_i,app.msd(:,1),app.msd(:,2),'k--','LineWidth',1)
            % hold(app.axeMSD_i,'off');
            %
            % app.axeMSD_i.XLabel.String='lag time (s)';
            % app.axeMSD_i.YLabel.String='MSD (m^2\rm)';
            % app.axeMSD_i.XLim=10.^[floor(log10(min(app.msd(:,1)))),ceil(log10(max(app.msd(:,1))))];
            % app.axeMSD_i.YLim=10.^[floor(log10(min(app.msd(:,2:4),[],'all'))),ceil(log10(max(app.msd(:,2),[],'all')))];
            % app.axeMSD_i.XTick=10.^(floor(log10(min(app.msd(:,1)))):ceil(log10(max(app.msd(:,1)))));
            % app.axeMSD_i.YTick=10.^(floor(log10(min(app.msd(:,2)))):ceil(log10(max(app.msd(:,2)))));
            % app.axeMSD_i.XMinorTick='on';
            % app.axeMSD_i.YMinorTick='on';
            % plots=app.axeMSD_i.Children;

            % single out the selected track

            traj_selected=app.tracks{app.track_ids==id_selected};

            cla(app.axeTrajectory,'reset');
            app.axeTrajectory.Box='on';

            legend(app.axeTrajectory,'off');

            plot(app.axeTrajectory,traj_selected(:,1),traj_selected(:,2),'o-');
            app.txtTrackID=text(app.axeTrajectory,...
                0.02,0.02,['Track #',num2str(id_selected)],...
                'Units','normalized');


            xlim=app.axeTrajectory.XLim;
            ylim=app.axeTrajectory.YLim;
            x_span=abs(diff(xlim));
            y_span=abs(diff(ylim));
            if x_span<y_span
                d=(y_span-x_span)/2;
                xlim(1)=xlim(1)-d;
                xlim(2)=xlim(2)+d;
                app.axeTrajectory.XLim=xlim;
            else
                d=(x_span-y_span)/2;
                ylim(1)=ylim(1)-d;
                ylim(2)=ylim(2)+d;
                app.axeTrajectory.YLim=ylim;

            end

            app.axeTrajectory.XTick=ceil(xlim(1)):floor(xlim(2));
            app.axeTrajectory.YTick=ceil(ylim(1)):floor(ylim(2));


        end

        % Double-clicked callback: lstTrajectories
        function lstTrajectoriesDoubleClicked(app, event)
            item = event.InteractionInformation.Item;
            existingItems=app.lstExcluded.Items;

            if ~ismember(app.lstTrajectories.Items{item},existingItems)
                app.lstExcluded.Items=[app.lstExcluded.Items,app.lstTrajectories.Items{item}];
                items = app.lstExcluded.Items;            % Get existing items
                sortedItems = sort(items);   % Sort them (alphabetically)
                app.lstExcluded.Items = sortedItems;
            end

        end

        % Value changed function: lstExcluded
        function lstExcludedValueChanged(app, event)
            value = app.lstExcluded.Value;
            app.lstTrajectories.Value=value;
            id_selected = str2double(value);

            % id_selected=str2double(cell2mat(app.lstTrajectories.Value));
            app.plots(app.plot_ids==id_selected).Color="#D95319";
            [app.plots(app.plot_ids~=id_selected).Color]=deal("#cccccc");
            app.axeMSD_i.Children=[app.plotMSD;...
                app.plots(app.plot_ids==id_selected);...
                app.plots(app.plot_ids~=id_selected)];

            % cla(app.axeMSD_i,'reset');
            % app.axeMSD_i.Box='on';
            %
            % legend(app.axeMSD_i,'off');

            % co=app.axeMSD_i.ColorOrder;
            % for i=1:length(co(:,1))
            %     co_2=rgb2hsv(co(i,:));
            %     co_2(3)=co_2(3)+(1-co_2(3))*3/4;
            %     co(i,:)=hsv2rgb(co_2);
            % end
            % app.axeMSD_i.ColorOrder=co;

            % Single out selected msd_i
            % id_selected=str2double(cell2mat(app.lstTrajectories.Value));
            % ind_rest=true(length(app.msd_i),1);
            % ind_rest(id_selected)=false;
            % msd_i_rest=app.msd_i(ind_rest);
            %
            % hold(app.axeMSD_i,'on')
            % cellfun(@(x) plot(app.axeMSD_i,x(:,1),x(:,2),'Color',"#cccccc",'LineWidth',1),msd_i_rest,'UniformOutput',0);
            % plot(app.axeMSD_i,app.msd_i{app.msd_i_ids==id_selected}(:,1),app.msd_i{app.msd_i_ids==id_selected}(:,2),'Color',	"#D95319",'LineWidth',1);
            %
            % app.axeMSD_i.XScale='log';
            % app.axeMSD_i.YScale='log';
            %
            % loglog(app.axeMSD_i,app.msd(:,1),app.msd(:,2),'k--','LineWidth',1)
            % hold(app.axeMSD_i,'off');
            %
            % app.axeMSD_i.XLabel.String='lag time (s)';
            % app.axeMSD_i.YLabel.String='MSD (m^2\rm)';
            % app.axeMSD_i.XLim=10.^[floor(log10(min(app.msd(:,1)))),ceil(log10(max(app.msd(:,1))))];
            % app.axeMSD_i.YLim=10.^[floor(log10(min(app.msd(:,2:4),[],'all'))),ceil(log10(max(app.msd(:,2),[],'all')))];
            % app.axeMSD_i.XTick=10.^(floor(log10(min(app.msd(:,1)))):ceil(log10(max(app.msd(:,1)))));
            % app.axeMSD_i.YTick=10.^(floor(log10(min(app.msd(:,2)))):ceil(log10(max(app.msd(:,2)))));
            % app.axeMSD_i.XMinorTick='on';
            % app.axeMSD_i.YMinorTick='on';
            %
            % plots=app.axeMSD_i.Children;

            % single out the selected track

            traj_selected=app.tracks{app.track_ids==id_selected};

            cla(app.axeTrajectory,'reset');
            app.axeTrajectory.Box='on';

            legend(app.axeTrajectory,'off');

            plot(app.axeTrajectory,traj_selected(:,1),traj_selected(:,2),'o-');
            app.txtTrackID=text(app.axeTrajectory,...
                0.02,0.02,['Track #',num2str(id_selected)],...
                'Units','normalized');
            xlim=app.axeTrajectory.XLim;
            ylim=app.axeTrajectory.YLim;
            x_span=abs(diff(xlim));
            y_span=abs(diff(ylim));
            if x_span<y_span
                d=(y_span-x_span)/2;
                xlim(1)=xlim(1)-d;
                xlim(2)=xlim(2)+d;
                app.axeTrajectory.XLim=xlim;
            else
                d=(x_span-y_span)/2;
                ylim(1)=ylim(1)-d;
                ylim(2)=ylim(2)+d;
                app.axeTrajectory.YLim=ylim;

            end

            app.axeTrajectory.XTick=ceil(xlim(1)):floor(xlim(2));
            app.axeTrajectory.YTick=ceil(ylim(1)):floor(ylim(2));

        end

        % Double-clicked callback: lstExcluded
        function lstExcludedDoubleClicked(app, event)
            item =event.InteractionInformation.Item;
            % item_ex=app.lstExcluded.Items{item};
            items = app.lstExcluded.Items;
            items(item)=[];
            app.lstExcluded.Items=items;
            % idx = find(strcmp(item, items));   % indices of the match
            % if ~isempty(idx)
            % items(idx) = [];                       % remove those entries
            % app.lstExcluded.Items = items;                      % update the listbox
            % end

        end

        % Close request function: TrajectoriesUIFigure
        function TrajectoriesUIFigureCloseRequest(app, event)
            traj_result(app.callingApp,app.lstExcluded.Items);
            % app.callingApp.excludedTrackID=app.lstExcluded.Items;

            delete(app)

        end

        % Button pushed function: btnRecalculate
        function btnRecalculateButtonPushed(app, event)
            close(app.TrajectoriesUIFigure)
        end

        % Button down function: axeMSD_i
        function axeMSD_iButtonDown(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TrajectoriesUIFigure = uifigure('Visible', 'off');
            app.TrajectoriesUIFigure.Name = 'Trajectories';
            app.TrajectoriesUIFigure.CloseRequestFcn = createCallbackFcn(app, @TrajectoriesUIFigureCloseRequest, true);
            app.axeMSD_i = uiaxes(app.TrajectoriesUIFigure);
            title(app.axeMSD_i, 'SP-MSD')
            xlabel(app.axeMSD_i, 'X')
            ylabel(app.axeMSD_i, 'Y')
            zlabel(app.axeMSD_i, 'Z')
            app.axeMSD_i.ButtonDownFcn = createCallbackFcn(app, @axeMSD_iButtonDown, true);
            app.axeTrajectory = uiaxes(app.TrajectoriesUIFigure);
            title(app.axeTrajectory, 'Tracks')
            xlabel(app.axeTrajectory, 'X (px)')
            ylabel(app.axeTrajectory, 'Y (px)')
            zlabel(app.axeTrajectory, 'Z')
            app.TracksLabel = uilabel(app.TrajectoriesUIFigure);
            app.TracksLabel.Text = 'Track#';
            app.lstTrajectories = uilistbox(app.TrajectoriesUIFigure);
            app.lstTrajectories.Items = {};
            app.lstTrajectories.ValueChangedFcn = createCallbackFcn(app, @lstTrajectoriesValueChanged, true);
            app.lstTrajectories.DoubleClickedFcn = createCallbackFcn(app, @lstTrajectoriesDoubleClicked, true);
            app.lstTrajectories.Value = {};
            app.ExcludeLabel = uilabel(app.TrajectoriesUIFigure);
            app.ExcludeLabel.Text = 'Exclude:';
            app.lstExcluded = uilistbox(app.TrajectoriesUIFigure);
            app.lstExcluded.Items = {};
            app.lstExcluded.ValueChangedFcn = createCallbackFcn(app, @lstExcludedValueChanged, true);
            app.lstExcluded.DoubleClickedFcn = createCallbackFcn(app, @lstExcludedDoubleClicked, true);
            app.lstExcluded.Value = {};
            app.btnRecalculate = uibutton(app.TrajectoriesUIFigure, 'push');
            app.btnRecalculate.ButtonPushedFcn = createCallbackFcn(app, @btnRecalculateButtonPushed, true);
            app.btnRecalculate.Text = 'Save';
            app.Label = uilabel(app.TrajectoriesUIFigure);
            app.Label.Text = 'NOTE: Previously excluded tracks will not appear here again unless you reload the track files.';
            % Show the figure after all components are created
            app.TrajectoriesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = traj_dlg(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TrajectoriesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrajectoriesUIFigure)
        end
    end
end